classdef alyxGUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		AlyxManager                   matlab.ui.Figure
		GridLayout                    matlab.ui.container.GridLayout
		LeftPanel                     matlab.ui.container.Panel
		GridLayout2                   matlab.ui.container.GridLayout
		ChangeButton                  matlab.ui.control.Button
		PasswordReset                 matlab.ui.control.DropDown
		TabGroup                      matlab.ui.container.TabGroup
		SearchTab                     matlab.ui.container.Tab
		GridLayout6                   matlab.ui.container.GridLayout
		text4554_18                   matlab.ui.control.Label
		aproject                      matlab.ui.control.EditField
		SessionssetManualtoNullLabel  matlab.ui.control.Label
		ManualLabel                   matlab.ui.control.Label
		searchButton                  matlab.ui.control.Button
		OKAlyxRequest                 CompactEditableComboBox
		ClearButton                   matlab.ui.control.Button
		endDate                       matlab.ui.control.DatePicker
		endDateLabel                  matlab.ui.control.Label
		startDate                     matlab.ui.control.DatePicker
		startDateLabel                matlab.ui.control.Label
		text4554_17                   matlab.ui.control.Label
		alocation                     matlab.ui.control.EditField
		text209_5                     matlab.ui.control.Label
		alab                          matlab.ui.control.EditField
		text4554_16                   matlab.ui.control.Label
		aresearcher                   matlab.ui.control.EditField
		text209_4                     matlab.ui.control.Label
		asubject                      matlab.ui.control.EditField
		UploadTab                     matlab.ui.container.Tab
		GridLayout4                   matlab.ui.container.GridLayout
		clearFolders                  matlab.ui.control.Button
		DryRun                        matlab.ui.control.CheckBox
		alyxSelectFolders             matlab.ui.control.Button
		alyxUpload                    matlab.ui.control.Button
		FolderList                    matlab.ui.control.ListBox
		DownloadTab                   matlab.ui.container.Tab
		GridLayout4_2                 matlab.ui.container.GridLayout
		SearchPatternLabel            matlab.ui.control.Label
		downloadPattern               matlab.ui.control.EditField
		SaveToFolder                  matlab.ui.control.Button
		downLoadFolder                matlab.ui.control.EditField
		alyxDownload                  matlab.ui.control.Button
		DownloadList                  matlab.ui.control.ListBox
		DefaultValuesTab              matlab.ui.container.Tab
		GridLayout5                   matlab.ui.container.GridLayout
		TaskProtocolEditFieldLabel_2  matlab.ui.control.Label
		OKAlyxTaskProtocol            matlab.ui.control.EditField
		text4554_15                   matlab.ui.control.Label
		OKAlyxProject                 matlab.ui.control.DropDown
		text4554_14                   matlab.ui.control.Label
		OKLabLocation                 matlab.ui.control.EditField
		text4554_12                   matlab.ui.control.Label
		OKAlyxBrainRegion             matlab.ui.control.DropDown
		text4554_11                   matlab.ui.control.Label
		OKAlyxProcedure               matlab.ui.control.DropDown
		text209_3                     matlab.ui.control.Label
		OKLabName                     matlab.ui.control.EditField
		text4554                      matlab.ui.control.Label
		OKResearcher                  matlab.ui.control.EditField
		text209                       matlab.ui.control.Label
		OKSubject                     matlab.ui.control.EditField
		text4554_9                    matlab.ui.control.Label
		text4554_8                    matlab.ui.control.Label
		OKDataBucket                  matlab.ui.control.EditField
		OKDataRepo                    matlab.ui.control.EditField
		alyxLOGIN                     matlab.ui.control.Button
		alyxLOGOUT                    matlab.ui.control.Button
		alyxIP                        matlab.ui.control.EditField
		alyxUser                      matlab.ui.control.EditField
		text4554_4                    matlab.ui.control.Label
		text4554_3                    matlab.ui.control.Label
		AlyxDatabaseInterfaceLabel    matlab.ui.control.Label
		Image                         matlab.ui.control.Image
		RightPanel                    matlab.ui.container.Panel
		GridLayout3                   matlab.ui.container.GridLayout
		Log                           matlab.ui.control.TextArea
		UITable                       matlab.ui.control.Table
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = public)
		alyx % Description
		uploader % Description
	end

	properties (Access = private)
		uiPrefsList cell = { ...
			'alyxIP' 'alyxUser'...
			'OKDataBucket' 'OKDataRepo'...
			'OKAlyxRequest' ...
			'aresearcher' 'alab' 'asubject' 'alocation' 'aproject' ...
			'DryRun' 'FolderList' ...
			'downLoadFolder' 'downloadPattern' ...
			'OKResearcher' 'OKLabName' ...
			'OKSubject' 'OKLabLocation'...
			}
	end

	methods (Access = private)

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function loadPrefs(app)
			if ~ispref('alyxgui'); return; end
			anyLoaded = false; prefnames = ""; a = 1;
			fn = fieldnames(app);
			for i = 1:length(fn)
				prfname = fn{i};
				if ispref('alyxgui',prfname) %pref exists
					if isprop(app, prfname) %ui widget exists
						myhandle = app.(prfname);
						prf = getpref('alyxgui', prfname);
						uiType = myhandle.Type;
						thisVal = '';
						switch uiType
							case 'uieditfield'
								if ischar(prf)
									myhandle.Value = prf;
									thisVal = prf;
								else
									myhandle.Value = num2str(prf);
									thisVal = myhandle.Value;
								end
							case 'uinumericeditfield'
								myhandle.Value = prf;
								thisVal = num2str(prf);
							case 'uicheckbox'
								if islogical(prf) || isnumeric(prf)
									myhandle.Value = prf;
									thisVal = num2str(prf);
								end
							case {'uidropdown','compacteditablecombobox'}
								str = myhandle.Items;
								if ischar(prf) && any(contains(prf, str))
									myhandle.Value = prf;
									thisVal = prf;
								elseif iscell(prf) & length(prf)==2
									myhandle.Items = prf{1};
									myhandle.Value = prf{2};
									thisVal = prf{2};
								end
							case 'uimenu'
								myhandle.Checked = prf;
								thisVal = char(prf);
							case 'uirockerswitch'
								if strcmpi(prf,'on') || strcmpi(prf,'off')
									myhandle.Value = prf;
									thisVal = prf;
								end
						end
						prefnames = [prefnames " " prfname " «" thisVal "»"];
						if ~mod(a,3); prefnames = [prefnames "\n"]; end
						if ~anyLoaded; anyLoaded = true; end
						a = a + 1;
					end
				end
			end
			if anyLoaded
				fprintf('\n≣≣≣≣⊱  alyxGUI Load Preferences:\n'); fprintf(join(prefnames)); fprintf('\n');
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function savePrefs(app)
			if ispref('alyxgui'); rmpref('alyxgui'); end
			anySaved = false; prefnames = ""; a = 1;
			for i = 1:length(app.uiPrefsList)
				prf = [];
				prfname = app.uiPrefsList{i};
				if ~isprop(app,prfname); continue; end
				try
					myhandle = app.(prfname);
					uiType = myhandle.Type;
					switch uiType
						case {'uinumericeditfield','uieditfield','uirockerswitch'}
							prf = myhandle.Value;
						case {'uidropdown','compacteditablecombobox'}
							prf = {myhandle.Items, myhandle.Value};
						case 'uicheckbox'
							prf = myhandle.Value;
							if ~islogical(prf); prf=logical(prf);end
						case 'uimenu'
							prf = myhandle.Checked;
					end
					if ~isempty(prf)
						setpref('alyxgui', prfname, prf);
						prefnames = [prefnames " " prfname " «"  strip(formattedDisplayText(prf)) "»"];
						if ~mod(a,3); prefnames = [prefnames "\n"]; end
						a = a + 1;
					end
					if ~anySaved; anySaved = true; end
				end
			end
			if anySaved; fprintf('\n≣≣≣≣⊱ CageLab Save Preferences:\n'); fprintf(join(prefnames)); fprintf('\n');end
		end
		
		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function postToLog(app, msg, prefix)
			if isempty(app.Log.Value)
				app.Log.Value = "=== === Log Start @ "+string(datetime)+" === ===";
			end
			if ~exist('prefix','var'); prefix = []; end
			if isstruct(msg)
				if isfield(msg,'message')
					msg = string(msg.message);
				else
					msg = formattedDisplayText(msg);
				end
			elseif iscell(msg)
				msg = formattedDisplayText(msg);
			else
				msg = string(msg);
			end
			if length(char(msg)) > 800
				msg = char(msg);
				msg = msg(1:800);
				msg = string(msg);
			end
			if ~isempty(prefix)
				app.Log.Value = [app.Log.Value; "["+string(datetime)+"] "+prefix+strip(msg,'right')];
			else
				app.Log.Value = [app.Log.Value; "["+string(datetime)+"] "+strip(msg,'right')];
			end
			scroll(app.Log,"bottom");drawnow limitrate;
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function showAlyxTable(app,data)
			if isempty(data)
				app.UITable.Data = [];
				postToLog(app,"Data was empty..");
				return
			end
			try
				tbl = struct2table(data,'asArray',true);
			catch ME
				postToLog(app,'Cannot show data as a table...','Warning');
				return
			end
			if ~isempty(tbl)
				app.UITable.Data = tbl;
				app.UITable.ColumnName = tbl.Properties.VariableNames;
				app.UITable.Enable = "on";
			else
				app.UITable.Data = table("Unknown error",'VariableNames',{'Error'});
				app.UITable.ColumnName = {'Error'};
				app.UITable.Enable = "off";
			end
		end

		
		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function openReport(app,report)
			if isempty(report); return; end
			try 
				fig = uifigure('Name', 'Alyx Upload Report', ...
					'Position', [100 100 1180 680]);
				root = uigridlayout(fig, [1 1]);
				rtext = uihtml(root);
				reportHTML = processHTML(app,report);
				rtext.HTMLSource = reportHTML;

			catch exception
				warning("Cannot open report...")
			end
		end

		function html = processHTML(app,lines)
			arguments
				app
				lines cell
			end

			if isempty(lines)
				html = '';
				return;
			end

			css = '<style> p { font-family: Arial, Helvetica, sans-serif; margin: 0 0 0.5em; }</style>';

			paragraphs = strings(1, numel(lines));
			for ii = 1:numel(lines)
				line = char(lines{ii});
				line = removeAnchors(line);
				line = htmlEscape(line);
				line = autolink(line);
				paragraphs(ii) = sprintf('<p>%s</p>', line);
			end

			html = strcat(css, strjoin(paragraphs, '\n'));
	
			function out = htmlEscape(in)
				out = strrep(in, '&', '&amp;');
				out = strrep(out, '<', '&lt;');
				out = strrep(out, '>', '&gt;');
				out = strrep(out, '"', '&quot;');
				out = strrep(out, '''', '&#39;');
			end
	
			function out = autolink(in)
				% Convert http/https links.
				out = regexprep(in, '(https?://[^\s<]+)', '<a href="$1">$1</a>');
				% Convert bare www links.
				out = regexprep(out, '(?<!href="http://)(www\.[^\s<]+)', '<a href="http://$1">$1</a>');
			end
	
			function [text, anchors] = removeAnchors(text)
				% Extract existing anchor tags and replace them with placeholders.
				re = 'href="(?<href>[^"]+)">(?<text>[^\<]+)';
				names = regexp(text, re, "names");
				if ~isempty(names)
					text = names.href;
				end
			end
		end

	end

	
	methods (Access = public)

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function searchSessions(app, ~)
			%> Build query parameters from controls and display matching sessions.
			try
				query = buildQuery(app);
				postToLog(app,sprintf('Searching /sessions with %i filter(s)...', ...
					numel(query) / 2));
				[sessions, statusCode] = app.alyx.getData('sessions', query{:});
				if statusCode ~= 200
					error('AlyxSessionBrowser:SearchFailed', ...
						'Alyx returned HTTP status %i.', statusCode);
				end

				showAlyxTable(app, sessions)
				postToLog(app,sprintf('Search complete: %i session(s).', ...
					length(sessions)));
				
			catch ex
				postToLog(app,getReport(ex, 'basic', 'hyperlinks', 'off'));
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function query = buildQuery(app)
			%> Convert non-empty filter controls to Alyx sessions query args.
			query = {};
			query = addTextQuery(app, query, 'subject', app.asubject.Value);
			query = addTextQuery(app, query, 'lab', app.alab.Value);
			query = addTextQuery(app, query, 'location', app.alocation.Value);
			query = addTextQuery(app, query, 'researcher', app.aresearcher.Value);
			query = addTextQuery(app, query, 'project', app.aproject.Value);

			sDate = app.startDate.Value;
			eDate = app.endDate.Value;
			if ~isnat(sDate) || ~isnat(eDate)
				if isnat(sDate)
					sDate = eDate;
				end
				if isnat(eDate)
					eDate = sDate;
				end
				if eDate < sDate
					error('AlyxSessionBrowser:InvalidDateRange', ...
						'End Date must be on or after Start Date.');
				end
				dateRange = sprintf('%s,%s', ...
					char(sDate, 'yyyy-MM-dd'), char(eDate, 'yyyy-MM-dd'));
				query = [query, {'date_range', dateRange}];
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function query = addTextQuery(app, query, name, value)
			%> Add a string query parameter when the UI value is non-empty.
			value = strtrim(string(value));
			if strlength(value) > 0
				query = [query, {name, char(value)}];
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function clearFilters(app, ~)
			%> Reset all search filters and clear the results table.
			app.asubject.Value = '';
			app.alab.Value = '';
			app.alocation.Value = '';
			app.aproject.Value = '';
			app.startDate.Value = NaT;
			app.endDate.Value = NaT;
			app.UITable.Data = [];
			app.UITable.ColumnName = {"Column 1" "Column 2" "Column 3"};
			postToLog(app,'Filters cleared.');
		end

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			
			app.alyx = alyxManager; % ALYX connection

			try
				app.loadPrefs(); % load saved prefs
			catch
				warning("AlyxGUI: couldn't load preferences on start...");
			end

			if isempty(app.OKAlyxRequest.Items)
				app.OKAlyxRequest.Items = {'null','sessions?subject=TestSubject', 'weighings?subject=TestSubject'};
				app.OKAlyxRequest.Value = 'sessions?subject=TestSubject';
			end

			app.alyx.baseURL = app.alyxIP.Value;
			app.alyx.user = app.alyxUser.Value;

			postToLog(app,"App loaded...");

		end

		% Button pushed function: alyxLOGIN
		function alyxLOGINPushed(app, event)

			app.AlyxManager.Pointer = 'watch';
			app.alyxLOGIN.Text = '.';

			if ~isa(app.alyx,'alyxManager')
				alyx = alyxManager;
			end
			alyx = app.alyx;
			alyx.user = app.alyxUser.Value;
			alyx.baseURL = app.alyxIP.Value;

			% this checks for AWS_ID, AWS_KEY and Alyx Password secrets
			% stored locally
			setSecrets(alyx);

			login(alyx);
			WaitSecs(0.1);

			if alyx.loggedIn
				app.alyxLOGIN.FontColor = [0 0.6 0];
				app.alyxLOGIN.Text = '..procedures';drawnow;
				try
					procedures = alyx.getData('procedures');
					procedures = {procedures(:).name};
					app.OKAlyxProcedure.Items = procedures;
					idx = find(contains(procedures,'Behavior training'));
					if ~isempty(idx); app.OKAlyxProcedure.ValueIndex = idx(1); end
				end
				app.alyxLOGIN.Text = '...projects';drawnow;
				try
					projects = alyx.getData('projects');
					projects = {projects(:).name};
					app.OKAlyxProject.Items = projects;
				end
				app.alyxLOGIN.Text = '....brain-regions';drawnow;
				if length(app.OKAlyxBrainRegion.Items) < 10
					try
						regions = alyx.getData('brain-regions');
						regions = {regions(:).name};
						app.OKAlyxBrainRegion.Items = regions;
						idx = find(contains(regions,'Cerebral cortex'));
						if ~isempty(idx); app.OKAlyxBrainRegion.ValueIndex = idx(1); end
					end
				end
				app.AlyxManager.Pointer = 'arrow';
				app.alyxLOGIN.BackgroundColor = [0.8 1 0.8];
				app.alyxLOGIN.Text = 'LOGGED IN ✔';
				app.searchButton.Enable = "on";
			else
				app.AlyxManager.Pointer = 'arrow';
				warning('Couldn''t login to Alyx, please check network');
				app.alyxLOGIN.FontColor = [0 0 0];
				app.alyxLOGIN.BackgroundColor = [0.96 0.96 0.96];
				app.alyxLOGIN.Text = 'LOGIN';
				app.searchButton.Enable = "off";
				app.searchButton.BackgroundColor = [0.96 0.96 0.96];
			end
		end

		% Button pushed function: alyxLOGOUT
		function alyxLOGOUTPushed(app, event)
			if isa(app.alyx,'alyxManager')
				logout(app.alyx);
				app.alyxLOGIN.FontColor = [0 0 0];
				app.alyxLOGIN.BackgroundColor = [0.96 0.96 0.96];
				app.alyxLOGIN.Text = 'LOGIN';
				app.AlyxManager.Pointer = 'arrow';
			end
		end

		% Button pushed function: ChangeButton
		function ChangeButtonPushed(app, event)
			value = upper(app.PasswordReset.Value);
			if contains(value,'ALYX')
				setSecret('AlyxPassword','Overwrite',true);
			elseif contains(value,'ID')
				setSecret('AWS_ID','Overwrite',true);
			elseif contains(value,'KEY')
				setSecret('AWS_KEY','Overwrite',true);
			end
		end

		% Button pushed function: searchButton
		function searchButtonPushed(app, event)
			alyx = app.alyx;

			if ~alyx.loggedIn
				postToLog(app,'Logging in to Alyx...');
				success = app.alyx.login();
				if ~success || ~app.alyx.loggedIn
					error('AlyxSessionBrowser:LoginFailed', ...
						'Could not log in to Alyx.');
				else
					app.alyxLOGIN.BackgroundColor = [0.8 1 0.8];
					app.alyxLOGIN.Text = 'LOGGED IN ✔';
				end
			end

			if alyx.loggedIn
				app.AlyxManager.Pointer = 'watch';
				ic = app.searchButton.Icon;
				try app.searchButton.Icon = ''; end
				app.searchButton.FontColor = [0.6 0.6 0.6];
				app.searchButton.Text = '...';

				request = app.OKAlyxRequest.Value;
				if ~isempty(request) && ~matches(request,"null")
					postToLog(app,"Searching: "+string(request));
					try
						[data, s] = alyx.getData(request);
						if s == 200 && ~isempty(data) && isstruct(data)
							showAlyxTable(app, data)
						else
							warning('Alyx request returned no data!');
							postToLog(app, 'Alyx request returned no data!')
						end
					end
				else
					searchSessions(app);
				end

				WaitSecs('YieldSecs',0.25);
				app.searchButton.FontColor = [0 0 0];
				try app.searchButton.Icon = ic; end
				app.searchButton.Text = 'Search';
				app.AlyxManager.Pointer = 'arrow';
			end
		end

		% Button pushed function: ClearButton
		function ClearButtonPushed(app, event)
			clearFilters(app);
		end

		% Value changed function: asubject
		function asubjectValueChanging(app, event)
			vals = app.OKAlyxRequest.Items;
			if ~matches('null',vals)
				app.OKAlyxRequest = [{'null'} vals(:)'];
			end
			app.OKAlyxRequest.Value = 'null';
		end

		% Button pushed function: alyxSelectFolders
		function alyxSelectFoldersButtonPushed(app, event)
			dir = uigetdir;
			if ischar(dir) && exist(dir,'dir')
				items = app.FolderList.Items;
				if length(items)==1 && isempty(items{1})
					app.FolderList.Items = {dir};
				else
					app.FolderList.Items = [items(:)' {dir}];
				end
				
			end
		end

		% Button pushed function: alyxUpload
		function alyxUploadButtonPushed(app, event)
			if isempty(app.FolderList.Items); return; end
			if isempty(app.uploader)
				app.uploader = alyxUploader('alyx',app.alyx);
			end

			app.uploader.labName = app.OKLabName.Value;
			app.uploader.user = app.OKResearcher.Value;
			app.uploader.researcherName = app.uploader.user;
			app.uploader.dataRepo = app.OKDataRepo.Value;
			
			dirs = app.FolderList.Items;
			for ii = 1:length(dirs)
				if exist(dirs{ii},'dir')
					try
						app.uploader.rootPath = dirs{ii};
						app.uploader.user = app.alyxUser.Value;
						report = app.uploader.run(app.DryRun.Value);
						openReport(app,report);
					catch exception
						getReport(exception)
					end
				end
			end

		end

		% Button pushed function: clearFolders
		function clearFoldersButtonPushed(app, event)
			app.FolderList.Items = {''};
		end

		% Close request function: AlyxManager
		function AlyxManagerCloseRequest(app, event)
			try app.savePrefs(); end %#ok<*TRYNC>
			delete(app);
		end

		% Button pushed function: alyxDownload
		function alyxDownloadButtonPushed(app, event)
			app.DownloadList.Items = {}; drawnow
			bucket = app.OKDataBucket.Value;
			endpoint = app.OKDataRepo.Value;
			patrn = app.downloadPattern.Value;
			local = app.downLoadFolder.Value;
			postToLog(app,"-------------------------------------------------------");
			postToLog(app,sprintf("Downloading <%s> to folder: %s",patrn, local))
			cd(app.downLoadFolder.Value)
			if ~isempty(patrn)
				m = minioManager(getSecret("AWS_ID"), getSecret("AWS_KEY"), endpoint);
				m.local = local;
				m.checkBucket(bucket);
				[out,s] = m.find(patrn,bucket);
				if ~isempty(out) && s
					postToLog(app,sprintf("  ↳ %i files matched",length(out)));
					for i=1:length(out)
						s = m.get(bucket,out(i).key);
						if s
							[p,f,e] = fileparts(out(i).key);
							app.DownloadList.Items{end+1} = [f e]; 
							drawnow;
							postToLog(app, ['  ↳ download: ' [f e]]); 
						end
					end
				else
					postToLog(app,"  ↳ no files were found")
				end
				clear m
			end
		end

		% Button pushed function: SaveToFolder
		function SaveToFolderButtonPushed(app, event)
			dir = uigetdir;
			if ischar(dir) && exist(dir,'dir')
				dir = regexprep(dir, getenv("HOME"), "~"+filesep);
				dir = regexprep(dir,'//','/');
				app.downLoadFolder.Value = dir;
			end
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.AlyxManager.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {710, 710};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {459, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.AlyxManager = uifigure('Visible', 'off');
			app.AlyxManager.Name = 'Alyx Database Manager';
			app.AlyxManager.CloseRequestFcn = createCallbackFcn(app, @AlyxManagerCloseRequest, true);
			app.AlyxManager.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.AlyxManager);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.LeftPanel);
			app.Image = uiimage(app.GridLayout2);
			app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'cebsit.png');
			app.AlyxDatabaseInterfaceLabel = uilabel(app.GridLayout2);
			app.AlyxDatabaseInterfaceLabel.Text = 'Alyx Database Interface';
			app.text4554_3 = uilabel(app.GridLayout2);
			app.text4554_3.Text = 'ALYX IP & Port';
			app.text4554_4 = uilabel(app.GridLayout2);
			app.text4554_4.Text = 'ALYX User';
			app.alyxUser = uieditfield(app.GridLayout2, 'text');
			app.alyxUser.Value = 'admin';
			app.alyxIP = uieditfield(app.GridLayout2, 'text');
			app.alyxIP.Value = 'http://172.16.102.30:8000';
			app.alyxLOGOUT = uibutton(app.GridLayout2, 'push');
			app.alyxLOGOUT.ButtonPushedFcn = createCallbackFcn(app, @alyxLOGOUTPushed, true);
			app.alyxLOGOUT.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.off.svg');
			app.alyxLOGOUT.Text = 'Log out';
			app.alyxLOGIN = uibutton(app.GridLayout2, 'push');
			app.alyxLOGIN.ButtonPushedFcn = createCallbackFcn(app, @alyxLOGINPushed, true);
			app.alyxLOGIN.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.svg');
			app.alyxLOGIN.Text = 'Login';
			app.OKDataRepo = uieditfield(app.GridLayout2, 'text');
			app.OKDataRepo.Value = 'http://172.16.102.77:9000';
			app.OKDataBucket = uieditfield(app.GridLayout2, 'text');
			app.OKDataBucket.Value = 'cognitionplatform';
			app.text4554_8 = uilabel(app.GridLayout2);
			app.text4554_8.Text = 'Data Repository URL';
			app.text4554_9 = uilabel(app.GridLayout2);
			app.text4554_9.Text = 'Data Bucket Name';
			app.TabGroup = uitabgroup(app.GridLayout2);
			app.SearchTab = uitab(app.TabGroup);
			app.SearchTab.Title = 'Search';
			app.GridLayout6 = uigridlayout(app.SearchTab);
			app.asubject = uieditfield(app.GridLayout6, 'text');
			app.asubject.ValueChangedFcn = createCallbackFcn(app, @asubjectValueChanging, true);
			app.asubject.Tag = 'OKTrainingName';
			app.asubject.Tooltip = {'Unique Subject Name / ID'};
			app.text209_4 = uilabel(app.GridLayout6);
			app.text209_4.Tag = 'text209';
			app.text209_4.Text = 'Subject🧠';
			app.aresearcher = uieditfield(app.GridLayout6, 'text');
			app.aresearcher.Tag = 'OKTrainingResearcherName';
			app.aresearcher.Tooltip = {'Experimenter name'};
			app.text4554_16 = uilabel(app.GridLayout6);
			app.text4554_16.Tag = 'text4554';
			app.text4554_16.Text = 'Researcher🧑🏻‍🔬';
			app.alab = uieditfield(app.GridLayout6, 'text');
			app.alab.Tag = 'OKTrainingName';
			app.alab.Tooltip = {'Lab / Group name'};
			app.text209_5 = uilabel(app.GridLayout6);
			app.text209_5.Tag = 'text209';
			app.text209_5.Text = 'Lab🥼';
			app.alocation = uieditfield(app.GridLayout6, 'text');
			app.alocation.Tag = 'OKTrainingResearcherName';
			app.alocation.Tooltip = {'Room / building where experiment is being performed'};
			app.text4554_17 = uilabel(app.GridLayout6);
			app.text4554_17.Tag = 'text4554';
			app.text4554_17.Text = 'Location🗺️';
			app.startDateLabel = uilabel(app.GridLayout6);
			app.startDateLabel.Text = 'Start Date';
			app.startDate = uidatepicker(app.GridLayout6);
			app.endDateLabel = uilabel(app.GridLayout6);
			app.endDateLabel.Text = 'End Date';
			app.endDate = uidatepicker(app.GridLayout6);
			app.ClearButton = uibutton(app.GridLayout6, 'push');
			app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
			app.ClearButton.Text = 'Clear';
			app.OKAlyxRequest = CompactEditableComboBox(app.GridLayout6);
			app.searchButton = uibutton(app.GridLayout6, 'push');
			app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
			app.searchButton.Icon = fullfile(pathToMLAPP, 'images', 'send.svg');
			app.searchButton.Text = 'Request';
			app.ManualLabel = uilabel(app.GridLayout6);
			app.ManualLabel.Text = '--- Manual ---';
			app.SessionssetManualtoNullLabel = uilabel(app.GridLayout6);
			app.SessionssetManualtoNullLabel.Text = '--- Sessions (set Manual to Null)  ---';
			app.aproject = uieditfield(app.GridLayout6, 'text');
			app.aproject.Tag = 'OKTrainingResearcherName';
			app.aproject.Tooltip = {'Experimenter name'};
			app.text4554_18 = uilabel(app.GridLayout6);
			app.text4554_18.Tag = 'text4554';
			app.text4554_18.Text = 'Project 📓';
			app.UploadTab = uitab(app.TabGroup);
			app.UploadTab.Title = 'Upload';
			app.GridLayout4 = uigridlayout(app.UploadTab);
			app.FolderList = uilistbox(app.GridLayout4);
			app.FolderList.Items = {};
			app.FolderList.Value = {};
			app.alyxUpload = uibutton(app.GridLayout4, 'push');
			app.alyxUpload.ButtonPushedFcn = createCallbackFcn(app, @alyxUploadButtonPushed, true);
			app.alyxUpload.Icon = fullfile(pathToMLAPP, 'images', 'send.svg');
			app.alyxUpload.Text = 'Upload';
			app.alyxSelectFolders = uibutton(app.GridLayout4, 'push');
			app.alyxSelectFolders.ButtonPushedFcn = createCallbackFcn(app, @alyxSelectFoldersButtonPushed, true);
			app.alyxSelectFolders.Icon = fullfile(pathToMLAPP, 'images', 'open.svg');
			app.alyxSelectFolders.Text = 'Select Folders';
			app.DryRun = uicheckbox(app.GridLayout4);
			app.DryRun.Text = 'Dry Run';
			app.clearFolders = uibutton(app.GridLayout4, 'push');
			app.clearFolders.ButtonPushedFcn = createCallbackFcn(app, @clearFoldersButtonPushed, true);
			app.clearFolders.Text = 'Clear';
			app.DownloadTab = uitab(app.TabGroup);
			app.DownloadTab.Title = 'Download';
			app.GridLayout4_2 = uigridlayout(app.DownloadTab);
			app.DownloadList = uilistbox(app.GridLayout4_2);
			app.DownloadList.Items = {};
			app.DownloadList.Value = {};
			app.alyxDownload = uibutton(app.GridLayout4_2, 'push');
			app.alyxDownload.ButtonPushedFcn = createCallbackFcn(app, @alyxDownloadButtonPushed, true);
			app.alyxDownload.Icon = fullfile(pathToMLAPP, 'images', 'down.svg');
			app.alyxDownload.Text = 'Download';
			app.downLoadFolder = uieditfield(app.GridLayout4_2, 'text');
			app.downLoadFolder.Value = '~/Downloads/';
			app.SaveToFolder = uibutton(app.GridLayout4_2, 'push');
			app.SaveToFolder.ButtonPushedFcn = createCallbackFcn(app, @SaveToFolderButtonPushed, true);
			app.SaveToFolder.Icon = fullfile(pathToMLAPP, 'images', 'folder-outline-add.svg');
			app.SaveToFolder.Text = 'Choose...';
			app.downloadPattern = uieditfield(app.GridLayout4_2, 'text');
			app.downloadPattern.Value = 'guanguan/2026-05-06/001';
			app.SearchPatternLabel = uilabel(app.GridLayout4_2);
			app.SearchPatternLabel.Text = 'Search Pattern';
			app.DefaultValuesTab = uitab(app.TabGroup);
			app.DefaultValuesTab.Title = 'Default Values';
			app.GridLayout5 = uigridlayout(app.DefaultValuesTab);
			app.OKSubject = uieditfield(app.GridLayout5, 'text');
			app.OKSubject.Tag = 'OKTrainingName';
			app.OKSubject.Tooltip = {'Unique Subject Name / ID'};
			app.OKSubject.Value = 'Simulcra';
			app.text209 = uilabel(app.GridLayout5);
			app.text209.Tag = 'text209';
			app.text209.Text = 'Subject🧠';
			app.OKResearcher = uieditfield(app.GridLayout5, 'text');
			app.OKResearcher.Tag = 'OKTrainingResearcherName';
			app.OKResearcher.Tooltip = {'Experimenter name'};
			app.OKResearcher.Value = 'Jane Doe';
			app.text4554 = uilabel(app.GridLayout5);
			app.text4554.Tag = 'text4554';
			app.text4554.Text = 'Researcher🧑🏻‍🔬';
			app.OKLabName = uieditfield(app.GridLayout5, 'text');
			app.OKLabName.Tag = 'OKTrainingName';
			app.OKLabName.Tooltip = {'Lab / Group name'};
			app.OKLabName.Value = 'Lab';
			app.text209_3 = uilabel(app.GridLayout5);
			app.text209_3.Tag = 'text209';
			app.text209_3.Text = 'Lab🥼';
			app.OKAlyxProcedure = uidropdown(app.GridLayout5);
			app.OKAlyxProcedure.Items = {'Behavior training/tasks', 'Imaging', 'Intraocular injection', 'Optical stimulation'};
			app.OKAlyxProcedure.Value = 'Behavior training/tasks';
			app.text4554_11 = uilabel(app.GridLayout5);
			app.text4554_11.Text = 'Procedure';
			app.OKAlyxBrainRegion = uidropdown(app.GridLayout5);
			app.OKAlyxBrainRegion.Items = {'Cerebral cortex', 'Thalamus', 'Hippocampus', 'Basal Ganglia'};
			app.OKAlyxBrainRegion.Value = 'Cerebral cortex';
			app.text4554_12 = uilabel(app.GridLayout5);
			app.text4554_12.Text = 'Brain Area';
			app.OKLabLocation = uieditfield(app.GridLayout5, 'text');
			app.OKLabLocation.Tag = 'OKTrainingResearcherName';
			app.OKLabLocation.Tooltip = {'Room / building where experiment is being performed'};
			app.OKLabLocation.Value = 'Room 10';
			app.text4554_14 = uilabel(app.GridLayout5);
			app.text4554_14.Tag = 'text4554';
			app.text4554_14.Text = 'Location🗺️';
			app.OKAlyxProject = uidropdown(app.GridLayout5);
			app.OKAlyxProject.Items = {'Default', 'Visual testing', 'Touch Task', 'Saccade Task'};
			app.OKAlyxProject.Value = 'Default';
			app.text4554_15 = uilabel(app.GridLayout5);
			app.text4554_15.Text = 'Project';
			app.OKAlyxTaskProtocol = uieditfield(app.GridLayout5, 'text');
			app.TaskProtocolEditFieldLabel_2 = uilabel(app.GridLayout5);
			app.TaskProtocolEditFieldLabel_2.Text = 'Task Protocol';
			app.PasswordReset = uidropdown(app.GridLayout2);
			app.PasswordReset.Items = {'Set Alyx Password', 'Set S3 ID', 'Set S3 KEY'};
			app.PasswordReset.Value = 'Set Alyx Password';
			app.ChangeButton = uibutton(app.GridLayout2, 'push');
			app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
			app.ChangeButton.Text = 'Change';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.RightPanel);
			app.UITable = uitable(app.GridLayout3);
			app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
			app.UITable.RowName = {};
			app.Log = uitextarea(app.GridLayout3);
			% Show the figure after all components are created
			app.AlyxManager.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = alyxGUI

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.AlyxManager)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.AlyxManager)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.AlyxManager)
		end
	end
end