classdef opticka_ui_app < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		OKRoot                     matlab.ui.Figure
		OKMenuFile                 matlab.ui.container.Menu
		OKMenuNewProtocol          matlab.ui.container.Menu
		OKMenuOpen                 matlab.ui.container.Menu
		OKMenuSave                 matlab.ui.container.Menu
		OKMenuStateInfo            matlab.ui.container.Menu
		OKMenuQuit                 matlab.ui.container.Menu
		OKMenuEdit                 matlab.ui.container.Menu
		OKMenuCut                  matlab.ui.container.Menu
		OKMenuCopy                 matlab.ui.container.Menu
		OKMenuPaste                matlab.ui.container.Menu
		OKMenuTools                matlab.ui.container.Menu
		OKMenuGrating              matlab.ui.container.Menu
		OKMenuGabor                matlab.ui.container.Menu
		OKMenuBar                  matlab.ui.container.Menu
		OKMenuDots                 matlab.ui.container.Menu
		OKMenuNDots                matlab.ui.container.Menu
		OKMenuSpot                 matlab.ui.container.Menu
		OKMenuTexture              matlab.ui.container.Menu
		OKMenuTargetInducer        matlab.ui.container.Menu
		OKMenuPlaid                matlab.ui.container.Menu
		OKMenuNoiseTexture         matlab.ui.container.Menu
		OKMenuLineTexture          matlab.ui.container.Menu
		Untitled_17                matlab.ui.container.Menu
		OKRunRFMapper              matlab.ui.container.Menu
		OKRunTrainingSession       matlab.ui.container.Menu
		OKRunExperiment            matlab.ui.container.Menu
		OKMenuIO                   matlab.ui.container.Menu
		OKuseEyeLink               matlab.ui.container.Menu
		OKuseDataPixx              matlab.ui.container.Menu
		OKuseLabJack               matlab.ui.container.Menu
		OKMenuPreferences          matlab.ui.container.Menu
		OKMenuCalibrateLuminance   matlab.ui.container.Menu
		OKMenuCalibrateSize        matlab.ui.container.Menu
		OKMenuLoadGamma            matlab.ui.container.Menu
		OKMenuSaveGamma            matlab.ui.container.Menu
		OKMenumanageCode           matlab.ui.container.Menu
		OKMenuEditConfiguration    matlab.ui.container.Menu
		OKMenuLogs                 matlab.ui.container.Menu
		OKMenuAllTimingLogs        matlab.ui.container.Menu
		OKMenuStimulusLog          matlab.ui.container.Menu
		OKMenuShowGammaPlots       matlab.ui.container.Menu
		OKMenurfMapperLog          matlab.ui.container.Menu
		OKToolbar                  matlab.ui.container.Toolbar
		OKToolbarInitialise        matlab.ui.container.toolbar.PushTool
		OKToolbarOpen              matlab.ui.container.toolbar.PushTool
		OKToolbarSave              matlab.ui.container.toolbar.PushTool
		OKToolbarDefinePath        matlab.ui.container.toolbar.PushTool
		OKToggleUI                 matlab.ui.container.toolbar.PushTool
		OKRFMapper                 matlab.ui.container.toolbar.PushTool
		OKStartTask                matlab.ui.container.toolbar.PushTool
		OKToolbarRun               matlab.ui.container.toolbar.PushTool
		OKToolbarStop              matlab.ui.container.toolbar.PushTool
		OKToolbarAbort             matlab.ui.container.toolbar.PushTool
		OKPanelTellOmniplex        matlab.ui.container.toolbar.PushTool
		OKPanelReconnectOmniplex   matlab.ui.container.toolbar.PushTool
		OKPanelSendOmniplex        matlab.ui.container.toolbar.PushTool
		OKPanelPingOmniplex        matlab.ui.container.toolbar.PushTool
		OKPanelDisconnectOmniplex  matlab.ui.container.toolbar.PushTool
		OKToolbarToggleRemote      matlab.ui.container.toolbar.ToggleTool
		TabGroup                   matlab.ui.container.TabGroup
		DisplayTab                 matlab.ui.container.Tab
		text8                      matlab.ui.control.Label
		OKbackgroundColour         matlab.ui.control.EditField
		text203                    matlab.ui.control.Label
		OKbenchmark                matlab.ui.control.CheckBox
		OKverbosityLevel           matlab.ui.control.EditField
		OKbitDepth                 matlab.ui.control.DropDown
		text191                    matlab.ui.control.Label
		OKlogFrames                matlab.ui.control.CheckBox
		text189                    matlab.ui.control.Label
		OKUseGamma                 matlab.ui.control.DropDown
		OKrecordMovie              matlab.ui.control.CheckBox
		OKNativeBeamPosition       matlab.ui.control.CheckBox
		OKAntiAliasing             matlab.ui.control.EditField
		OKGLDst                    matlab.ui.control.DropDown
		OKGLSrc                    matlab.ui.control.DropDown
		OKDebug                    matlab.ui.control.CheckBox
		OKVerbose                  matlab.ui.control.CheckBox
		OKUsePhotoDiode            matlab.ui.control.CheckBox
		OKHideFlash                matlab.ui.control.CheckBox
		text101                    matlab.ui.control.Label
		text100                    matlab.ui.control.Label
		OKSelectScreen             matlab.ui.control.DropDown
		text83                     matlab.ui.control.Label
		OKOpenGLBlending           matlab.ui.control.CheckBox
		text7                      matlab.ui.control.Label
		text6                      matlab.ui.control.Label
		OKscreenYOffset            matlab.ui.control.EditField
		OKscreenXOffset            matlab.ui.control.EditField
		OKWindowSize               matlab.ui.control.EditField
		text5                      matlab.ui.control.Label
		text4                      matlab.ui.control.Label
		text3                      matlab.ui.control.Label
		OKpixelsPerCm              matlab.ui.control.EditField
		OKMonitorDistance          matlab.ui.control.EditField
		text102                    matlab.ui.control.Label
		uipanel45                  matlab.ui.container.ButtonGroup
		OKrealTime                 matlab.ui.control.CheckBox
		text107                    matlab.ui.control.Label
		OKtrialTime                matlab.ui.control.EditField
		text104                    matlab.ui.control.Label
		OKitTime                   matlab.ui.control.EditField
		text10                     matlab.ui.control.Label
		text9                      matlab.ui.control.Label
		OKisTime                   matlab.ui.control.EditField
		OKnBlocks                  matlab.ui.control.EditField
		text106                    matlab.ui.control.Label
		OKRandomSeed               matlab.ui.control.EditField
		text105                    matlab.ui.control.Label
		OKrandomGenerator          matlab.ui.control.DropDown
		OKRandomise                matlab.ui.control.CheckBox
		TaskTab                    matlab.ui.container.Tab
		OKTrainingFileName         matlab.ui.control.Label
		OKRefreshStateInfo         matlab.ui.control.Button
		OKTrainingText             matlab.ui.control.TextArea
		text4554                   matlab.ui.control.Label
		OKTrainingResearcherName   matlab.ui.control.EditField
		OKEditStateFileButon       matlab.ui.control.Button
		OKLoadStateButton          matlab.ui.control.Button
		text209                    matlab.ui.control.Label
		OKTrainingName             matlab.ui.control.EditField
		OthersTab                  matlab.ui.container.Tab
		OKeyeOccluder              matlab.ui.control.EditField
		text208                    matlab.ui.control.Label
		OKOmniplexEnable           matlab.ui.control.CheckBox
		text192                    matlab.ui.control.Label
		OKSettingsmovieCodec       matlab.ui.control.EditField
		text185                    matlab.ui.control.Label
		OKOmniplexPort             matlab.ui.control.EditField
		OKOmniplexIP               matlab.ui.control.EditField
		OKSettingsmovieType        matlab.ui.control.DropDown
		text183                    matlab.ui.control.Label
		text182                    matlab.ui.control.Label
		OKSettingsmoviePrecision   matlab.ui.control.CheckBox
		OKSettingsmovieFrames      matlab.ui.control.EditField
		OKSettingsmovieSize        matlab.ui.control.EditField
		text181                    matlab.ui.control.Label
		uipanel41                  matlab.ui.container.Panel
		uipanel35                  matlab.ui.container.Panel
		OKProtocolsList            matlab.ui.control.ListBox
		OKHistoryDelete            matlab.ui.control.Button
		OKHistoryDown              matlab.ui.control.Button
		OKHistoryUp                matlab.ui.control.Button
		OKProtocolDelete           matlab.ui.control.Button
		OKProtocolDuplicate        matlab.ui.control.Button
		OKProtocolSave             matlab.ui.control.Button
		OKProtocolLoad             matlab.ui.control.Button
		text97                     matlab.ui.control.Label
		text96                     matlab.ui.control.Label
		OKHistoryList              matlab.ui.control.ListBox
		OKPanelIndividual          matlab.ui.container.ButtonGroup
		OKPanelStimulus            matlab.ui.container.Panel
		OKPanelStimulusText        matlab.ui.control.Label
		uipanel44                  matlab.ui.container.ButtonGroup
		OKVarList                  matlab.ui.control.ListBox
		OKInspectVariable          matlab.ui.control.Button
		OKEditVariable             matlab.ui.control.Button
		OKCopyVariable             matlab.ui.control.Button
		OKDeleteVariable           matlab.ui.control.Button
		OKAddVariable              matlab.ui.control.Button
		OKPanelVariables           matlab.ui.container.Panel
		OKVariableList             matlab.ui.control.ListBox
		OKVariableOffset           matlab.ui.control.EditField
		OKVariableName             matlab.ui.control.EditField
		OKVariableValues           matlab.ui.control.EditField
		OKVariableStimuli          matlab.ui.control.EditField
		text178                    matlab.ui.control.Label
		OKCopyVariableNameValues   matlab.ui.control.Button
		OKVariablesLog             matlab.ui.control.Button
		OKVariablesLinear          matlab.ui.control.Button
		OKCopyVariableName         matlab.ui.control.Button
		text142                    matlab.ui.control.Label
		text141                    matlab.ui.control.Label
		text140                    matlab.ui.control.Label
		OKOptickaVersion           matlab.ui.control.Label
		OKOmniplexStatus           matlab.ui.control.Label
		OKPanelStimulusList        matlab.ui.container.ButtonGroup
		OKStimulusRunSingle        matlab.ui.control.Button
		OKStimList                 matlab.ui.control.ListBox
		OKStimulusRunAllBenchmark  matlab.ui.control.Button
		OKStimulusRunAll           matlab.ui.control.Button
		OKStimulusRunBenchmark     matlab.ui.control.Button
		OKStimulusRun              matlab.ui.control.Button
		OKStimulusUp               matlab.ui.control.Button
		OKStimulusDown             matlab.ui.control.Button
		OKModifyStimulus           matlab.ui.control.Button
		OKInspectStimulus          matlab.ui.control.Button
		OKCopyStimulus             matlab.ui.control.Button
		OKDeleteStimulus           matlab.ui.control.Button
		OKAddStimulus              matlab.ui.control.Button
	end


	methods (Access = private)
		function OKMenuCheckIO_Callback(app, hObject, eventdata, handles)
			% hObject    handle to OKMenuCheckIO (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')

			end
		end

		function OKRemoteIP_Callback(app, hObject, eventdata, handles)
			% hObject    handle to OKRemoteIP (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)

			% Hints: get(hObject,'String') returns contents of OKRemoteIP as text
			%        str2double(get(hObject,'String')) returns contents of OKRemoteIP as a double
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		function OKRemotePort_Callback(app, hObject, eventdata, handles)
			% hObject    handle to OKRemotePort (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)

			% Hints: get(hObject,'String') returns contents of OKRemotePort as text
			%        str2double(get(hObject,'String')) returns contents of OKRemotePort as a double
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		function OKSerialPortName_Callback(app, hObject, eventdata, handles)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		function OKTrainingName_CreateFcn(app, hObject, eventdata, handles)
			% hObject    handle to OKTrainingName (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    empty - handles not created until after all CreateFcns called

			% Hint: edit controls usually have a white background on Windows.
			%       See ISPC and COMPUTER.
			if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
				set(hObject,'BackgroundColor','white');
			end
		end

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function opticka_ui_OpeningFcn(app, varargin)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

			% This function has no output args, see OutputFcn.
			% hObject    handle to figure
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			% varargin   command line arguments to opticka_ui (see VARARGIN)

			% Choose default command line output for opticka_ui
			handles.output = hObject;

			% Update handles structure
			guidata(hObject, handles);
		end

		% Button pushed function: OKAddStimulus
		function OKAddStimulus_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKAddStimulus (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isfield(o.store,'visibleStimulus')
					o.store.visibleStimulus.readPanel();
					o.r.stimuli{o.r.stimuli.n+1} = o.store.visibleStimulus.clone();
					%o.r.stimuli{o.r.stimuli.n}.name = 'Stimulus';
					o.addStimulus;
					if o.r.stimuli.n > 0
						set(handles.OKAddStimulus,'Enable','on');
						set(handles.OKDeleteStimulus,'Enable','on');
						set(handles.OKModifyStimulus,'Enable','on');
						set(handles.OKCopyStimulus,'Enable','on');
						set(handles.OKStimulusUp,'Enable','on');
						set(handles.OKStimulusDown,'Enable','on');
						set(handles.OKStimulusRun,'Enable','on');
						set(handles.OKStimulusRunBenchmark,'Enable','on');
						set(handles.OKStimulusRunAll,'Enable','on');
						set(handles.OKStimulusRunAllBenchmark,'Enable','on');
						set(handles.OKStimulusRunSingle,'Enable','on');
					end
				end
			end
		end

		% Button pushed function: OKAddVariable
		function OKAddVariable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.addVariable;
				if o.r.task.nVars > 0
					set(handles.OKDeleteVariable,'Enable','on');
					set(handles.OKCopyVariable,'Enable','on');
					set(handles.OKEditVariable,'Enable','on');
				end
			end
		end

		% Value changed function: OKAntiAliasing
		function OKAntiAliasing_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Button pushed function: OKCopyStimulus
		function OKCopyStimulus_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKCopyStimulus (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v = get(handles.OKStimList,'Value');
				o.r.stimuli{o.r.stimuli.n+1} = o.r.stimuli{v}.clone;
				o.r.stimuli{o.r.stimuli.n}.name = ['Stimulus #' num2str(o.r.stimuli.n)];
				o.addStimulus;
			end
		end

		% Button pushed function: OKCopyVariableNameValues
		function OKCopyVariableNameValues_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			string = get(handles.OKVariableList,'String');
			value = get(handles.OKVariableList,'Value');
			string=string{value};
			set(handles.OKVariableName,'String',string);

			switch string
				case 'angle'
					string = num2str(-90:45:90);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'direction'
					string = num2str(-90:45:90);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'phase'
					string = num2str(0:22.5:180);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'size'
					string = num2str([0 0.1 0.2 0.35 0.5 0.75 1 2 4 6 8]);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'contrast'
					string = num2str(0:0.1:1);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'sf'
					string = num2str([0 0.1 0.5 0.7 1 1.5 2 3 4 5 6]);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'tf'
					string = num2str([0.5 1 2 3 4 5]);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'xPosition'
					string = num2str(-1:0.2:1);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
				case 'yPosition'
					string = num2str(-1:0.2:1);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(handles.OKVariableValues,'String',string);
			end
		end

		% Button pushed function: OKCopyVariableName
		function OKCopyVariableName_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKCopyVariableName (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			string = get(handles.OKVariableList,'String');
			value = get(handles.OKVariableList,'Value');
			string=string{value};
			set(handles.OKVariableName,'String',string);
		end

		% Button pushed function: OKCopyVariable
		function OKCopyVariable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.copyVariable;
			end
		end

		% Value changed function: OKDebug
		function OKDebug_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Button pushed function: OKDeleteStimulus
		function OKDeleteStimulus_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKDeleteStimulus (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.deleteStimulus;
				if o.r.stimuli.n == 0
					set(handles.OKAddStimulus,'Enable','off');
					set(handles.OKDeleteStimulus,'Enable','off');
					set(handles.OKModifyStimulus,'Enable','off');
					set(handles.OKCopyStimulus,'Enable','off');
					set(handles.OKStimulusUp,'Enable','off');
					set(handles.OKStimulusDown,'Enable','off');
					set(handles.OKStimulusRun,'Enable','off');
					set(handles.OKStimulusRunBenchmark,'Enable','off');
					set(handles.OKStimulusRunAll,'Enable','off');
					set(handles.OKStimulusRunAllBenchmark,'Enable','off');
					set(handles.OKStimulusRunSingle,'Enable','off');
				end
			end
		end

		% Button pushed function: OKDeleteVariable
		function OKDeleteVariable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.deleteVariable;
				if o.r.task.nVars < 1
					set(handles.OKDeleteVariable,'Enable','off');
					set(handles.OKCopyVariable,'Enable','off');
					set(handles.OKEditVariable,'Enable','off');
				end
			end
		end

		% Button pushed function: OKEditStateFileButon
		function OKEditStateFileButon_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKEditStateFileButon (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if exist(o.r.paths.stateInfoFile,'file')
					edit(o.r.paths.stateInfoFile);
				end
				o.getStateInfo();
			end
		end

		% Button pushed function: OKEditVariable
		function OKEditVariable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.editVariable;
			end
		end

		% Value changed function: OKGLDst
		function OKGLDst_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKGLSrc
		function OKGLSrc_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKHideFlash
		function OKHideFlash_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Button pushed function: OKInspectStimulus
		function OKInspectStimulus_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v = get(handles.OKStimList,'Value');
				if v > 0;
					uiinspect(o.r.stimuli{v});
				end
			end
		end

		% Button pushed function: OKInspectVariable
		function OKInspectVariable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v = get(handles.OKVarList,'Value');
				if v > 0;
					uiinspect(o.r.task.nVar(v));
				end
			end
		end

		% Button pushed function: OKLoadStateButton
		function OKLoadStateButton_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKLoadStateButton (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('LoadStateInfo');
				o.getStateInfo();
			end
		end

		% Menu selected function: OKMenuAllTimingLogs
		function OKMenuAllTimingLogs_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuAllTimingLogs (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.getRunLog;
			end
		end

		% Menu selected function: OKMenuBar
		function OKMenuBar_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuBar (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'barStimulus')
					o.store.barStimulus=barStimulus('name','Bar Stimulus');
				end
				o.store.visibleStimulus = o.store.barStimulus;
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
				set(handles.OKPanelStimulusText,'String','')
			end
		end

		% Menu selected function: OKMenuCalibrateLuminance
		function OKMenuCalibrateLuminance_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuCalibrateLuminance (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v=get(handles.OKSelectScreen,'Value');
				inp = struct('screen',v-1);
				if isa(o.r.screen.gammaTable,'calibrateLuminance')
					o.r.screen.gammaTable.run;
				else
					c = calibrateLuminance(inp);
					o.r.screen.gammaTable = c;
					c.filename = [o.paths.calibration filesep 'Cal-' date '-' c.comments];
					o.saveCalibration;
				end
				set(handles.OKUseGamma,'Value',1)
				o.r.screen.gammaTable.choice = 0;
				set(handles.OKUseGamma,'String',['None'; 'Gamma'; o.r.screen.gammaTable.analysisMethods]);
			end
		end

		% Menu selected function: OKMenuCalibrateSize
		function OKMenuCalibrateSize_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuCalibrateSize (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			v=get(handles.OKSelectScreen,'Value');
			s=str2num(get(handles.OKMonitorDistance,'String'));
			[~,dpc]=calibrateSize(v-1,s);
			set(handles.OKpixelsPerCm,'String',num2str(dpc));
		end

		% Menu selected function: OKMenuDots
		function OKMenuDots_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuSpot (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'dotsStimulus')
					o.store.dotsStimulus=dotsStimulus('name', 'Coherent Dots Stimulus',...
						'speed', 2, 'colour', [1 1 1]);
				end
				o.store.dotsStimulus.speed = 2;
				o.store.visibleStimulus = o.store.dotsStimulus;
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
				set(handles.OKPanelStimulusText,'String','')
			end
		end

		% Menu selected function: OKMenuGabor
		function OKMenuGabor_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuGabor (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'gaborStimulus')
					o.store.gaborStimulus=gaborStimulus('name','Gabor Stimulus');
				end
				o.store.visibleStimulus = o.store.gaborStimulus;
				set(handles.OKPanelStimulusText,'String','')
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
			end
		end

		% Menu selected function: OKMenuGrating
		function OKMenuGrating_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuGrating (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'gratingStimulus')
					o.store.gratingStimulus=gratingStimulus('name','Grating Stimulus');
				end
				o.store.visibleStimulus = o.store.gratingStimulus;
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
				set(handles.OKPanelStimulusText,'String','')
			end
		end

		% Menu selected function: OKMenuLoadGamma
		function OKMenuLoadGamma_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuLoadGamma (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				uiopen('~/MatlabFiles/Calibration')
				if exist('tmp','var') && isa(tmp,'calibrateLuminance')
					o.r.screen.gammaTable = tmp;
					clear tmp;
					if get(handles.OKUseGamma,'Value') > length(['None'; 'Gamma'; o.r.screen.gammaTable.analysisMethods])
						set(handles.OKUseGamma,'Value',1);
						o.r.screen.gammaTable.choice = 0;
					else
						o.r.screen.gammaTable.choice = get(handles.OKUseGamma,'Value')-1;
					end
					set(handles.OKUseGamma,'String',['None'; 'Gamma'; o.r.screen.gammaTable.analysisMethods]);
				end
			end
		end

		% Menu selected function: OKMenuNDots
		function OKMenuNDots_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuNDots (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'ndotsStimulus')
					o.store.ndotsStimulus=ndotsStimulus('name','Newsome Dots Stimulus',...
						'speed', 2, 'colour', [1 1 1]);
				end
				o.store.ndotsStimulus.speed = 2;
				o.store.visibleStimulus = o.store.ndotsStimulus;
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
				set(handles.OKPanelStimulusText,'String','')
			end
		end

		% Menu selected function: OKMenuNewProtocol
		function OKMenuNewProtocol_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuNewProtocol (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r = [];
				o.store.evnt = [];
				o.store = rmfield(o.store,'evnt');
				o.store.visibleStimulus=[];
				o.store = rmfield(o.store,'visibleStimulus');
				o.clearStimulusList;
				o.clearVariableList;
				o.getScreenVals;
				o.getTaskVals;
			end
		end

		% Menu selected function: OKMenuNoiseTexture
		function OKMenuNoiseTexture_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuNoiseTexture (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.store.visibleStimulus='dots';

			end
		end

		% Menu selected function: OKMenuOpen
		function OKMenuOpen_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuOpen (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('loadProtocol','1');
			end
		end

		% Menu selected function: OKMenuQuit
		function OKMenuQuit_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuQuit (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				try
					o = getappdata(handles.output,'o');
					o.savePrefs;
					rmappdata(handles.output,'o');
					clear o;
				catch ME
					fprintf('>>> Opticka failed to clear all data on close...');
					rethrow ME
				end
			end
			close(gcf);
		end

		% Menu selected function: OKMenuSaveGamma
		function OKMenuSaveGamma_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuSaveGamma (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.screen.gammaTable,'calibrateLuminance')
					tmp=o.r.screen.gammaTable;
					uisave('tmp',[o.paths.calibration filesep 'Calibration-' date '-' o.r.screen.gammaTable.comments]);
				end
			end
		end

		% Menu selected function: OKMenuSave
		function OKMenuSave_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuSave (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('saveProtocol');
			end
		end

		% Menu selected function: OKMenuShowGammaPlots
		function OKMenuShowGammaPlots_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuShowGammaPlots (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.screen.gammaTable,'calibrateLuminance')
					o.r.screen.gammaTable.plot;
				end
			end
		end

		% Menu selected function: OKMenuSpot
		function OKMenuSpot_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuSpot (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')

				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'spotStimulus')
					o.store.spotStimulus=spotStimulus('name','Spot Stimulus');
				end
				o.store.visibleStimulus = o.store.spotStimulus;
				set(handles.OKPanelStimulusText,'String','')
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
			end
		end

		% Menu selected function: OKMenuStateInfo
		function OKMenuStateInfo_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuStateInfo (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('LoadStateInfo');
				o.getStateInfo();
			end
		end

		% Menu selected function: OKMenuStimulusLog
		function OKMenuStimulusLog_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuStimulusLog (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.task.showLog;
			end
		end

		% Menu selected function: OKMenuTargetInducer
		function OKMenuTargetInducer_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuSpot (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'targetInducerStimulus')
					o.store.targetInducerStimulus=targetInducerStimulus('name','Target-Inducer Stimulus');
				end
				o.store.visibleStimulus = o.store.targetInducerStimulus;
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
				set(handles.OKPanelStimulusText,'String','')
			end
		end

		% Menu selected function: OKMenuTexture
		function OKMenuTexture_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuTexture (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');

				if isfield(o.store,'visibleStimulus');
					o.store.visibleStimulus.closePanel();
					o.store = rmfield(o.store,'visibleStimulus');
				end

				set(handles.OKPanelStimulusText,'String','Loading Stimulus Panel...'); drawnow

				if ~isfield(o.store,'textureStimulus')
					o.store.textureStimulus=textureStimulus('name','Picture / Texture Stimulus');
				end
				o.store.visibleStimulus = o.store.textureStimulus;
				o.store.visibleStimulus.makePanel(handles.OKPanelStimulus);
				set(handles.OKAddStimulus,'Enable','on');
				set(handles.OKPanelStimulusText,'String','')
			end
		end

		% Menu selected function: OKMenumanageCode
		function OKMenumanageCode_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenumanageCode (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			manageCode
		end

		% Button pushed function: OKModifyStimulus
		function OKModifyStimulus_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v = get(handles.OKStimList,'Value');
				if v > 0;
					seditor(o.r.stimuli{v}, handles.output);
				end

			end
		end

		% Value changed function: OKMonitorDistance
		function OKMonitorDistance_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKNativeBeamPosition
		function OKNativeBeamPosition_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKOmniplexEnable
		function OKOmniplexEnable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKOmniplexEnable (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			% Hint: get(hObject,'Value') returns toggle state of OKOmniplexEnable
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if get(hObject,'Value') < 1
					set(handles.OKOmniplexIP,'Enable','off');
					set(handles.OKOmniplexPort,'Enable','off');
					set(handles.OKPanelTellOmniplex,'Enable','off');
					set(handles.OKPanelReconnectOmniplex,'Enable','off');
					set(handles.OKPanelSendOmniplex,'Enable','off');
					set(handles.OKPanelPingOmniplex,'Enable','off');
					set(handles.OKPanelDisconnectOmniplex,'Enable','off');
				else
					set(handles.OKOmniplexIP,'Enable','on');
					set(handles.OKOmniplexPort,'Enable','on');
					set(handles.OKPanelTellOmniplex,'Enable','on');
					set(handles.OKPanelReconnectOmniplex,'Enable','on');
					set(handles.OKPanelSendOmniplex,'Enable','on');
					set(handles.OKPanelPingOmniplex,'Enable','on');
					set(handles.OKPanelDisconnectOmniplex,'Enable','on');
				end
			end
		end

		% Value changed function: OKOpenGLBlending
		function OKOpenGLBlending_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Clicked callback: OKPanelDisconnectOmniplex
		function OKPanelDisconnectOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.oc,'dataConnection')
					o.disconnectOmniplex;
				end
			end
		end

		% Clicked callback: OKPanelPingOmniplex
		function OKPanelPingOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				rAddress = get(handles.OKOmniplexIP,'String');
				status = o.ping(rAddress);
				if status > 0
					set(o.h.OKOmniplexStatus,'String','Omniplex: machine ping ERROR!')
				else
					if isa(o.oc,'dataConnection') && o.oc.isOpen == 1
						o.oc.write('--ping--');
						loop = 1;
						while loop < 8
							in = o.oc.read(0);
							fprintf('\n{opticka said: %s}\n',in)
							if regexpi(in,'ping')
								fprintf('\nWe can ping omniplex master on try: %d\n',loop)
								set(handles.OKOmniplexStatus,'String','Omniplex: connected and pinged')
								break
							else
								fprintf('\nOmniplex master not responding, try: %d\n',loop)
								set(handles.OKOmniplexStatus,'String','Omniplex: not responding')
							end
							loop=loop+1;
							pause(0.1);
						end
					end
				end
			end
		end

		% Clicked callback: OKPanelReconnectOmniplex
		function OKPanelReconnectOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.oc,'dataConnection')
					o.oc.closeAll;
					o.connectToOmniplex;
				end
			end
		end

		% Clicked callback: OKPanelSendOmniplex
		function OKPanelSendOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.oc,'dataConnection')
					o.sendOmniplexStimulus;
				end
			end
		end

		% Clicked callback: OKPanelTellOmniplex
		function OKPanelTellOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				resp = questdlg('Is opxOnline running on the Omniplex machine?','Check OPX','No');
				if strcmpi(resp,'Yes')
					o.connectToOmniplex
				end
			end
		end

		% Button pushed function: OKProtocolDelete
		function OKProtocolDelete_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKProtocolDelete (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('deleteProtocol');
			end
		end

		% Button pushed function: OKProtocolDuplicate
		function OKProtocolDuplicate_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKProtocolDuplicate (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('duplicateProtocol');
			end
		end

		% Button pushed function: OKProtocolLoad
		function OKProtocolLoad_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKProtocolLoad (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('loadProtocol');
			end
		end

		% Button pushed function: OKProtocolSave
		function OKProtocolSave_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKProtocolSave (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('saveProtocol');
			end
		end

		% Clicked callback: OKRFMapper
		function OKRFMapper_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKRFMapper (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.store.rfLog = [];
				rf=rfMapper;
				rf.run(o.r);
				o.store.rfLog = rf;
				clear rf;
			end
		end

		% Value changed function: OKRandomSeed
		function OKRandomSeed_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKRandomSeed (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			% Hints: get(hObject,'String') returns contents of OKRandomSeed as text
			%        str2double(get(hObject,'String')) returns contents of OKRandomSeed as a double
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Value changed function: OKRandomise
		function OKRandomise_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Button pushed function: OKRefreshStateInfo
		function OKRefreshStateInfo_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKRefreshStateInfo (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getStateInfo();
			end
		end

		% Menu selected function: OKRunRFMapper
		function OKRunRFMapper_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKRunRFMapper (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.store.rfLog = [];
				rf=rfMapper;
				rf.run(o.r);
				o.store.rfLog = rf;
				clear rf;
			end
		end

		% Value changed function: OKSelectScreen
		function OKSelectScreen_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKSettingsmovieCodec
		function OKSettingsmovieCodec_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.screen.movieSettings.codec=get(hObject,'String');
			end
		end

		% Value changed function: OKSettingsmovieFrames
		function OKSettingsmovieFrames_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.screen.movieSettings.nFrames=str2num(get(hObject,'String')); %#ok<*ST2NM>
			end
		end

		% Value changed function: OKSettingsmoviePrecision
		function OKSettingsmoviePrecision_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.screen.movieSettings.quality=get(hObject,'Value');
			end
		end

		% Value changed function: OKSettingsmovieSize
		function OKSettingsmovieSize_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.screen.movieSettings.size=str2num(get(hObject,'String'));
			end
		end

		% Value changed function: OKSettingsmovieType
		function OKSettingsmovieType_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.screen.movieSettings.type=get(hObject,'Value');
			end
		end

		% Clicked callback: OKStartTask
		function OKStartTask_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKStartTask (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r,'runExperiment')
					%o.r.screenSettings.optickahandle = handles.output;
					initialiseSave(o.r, o.paths.savedData)
					if ~isempty(regexp(o.comment, '^Protocol','once'))
						o.r.comment = o.comment;
					end
					runTask(o.r);
				end
			end
		end

		% Value changed function: OKStimList
		function OKStimList_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.editStimulus;
			end
		end

		% Button pushed function: OKStimulusDown
		function OKStimulusDown_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				value = get(handles.OKStimList,'Value'); %where are we in the stimulus list
				slength = o.r.stimuli.n;
				if value < slength
					idx = 1:slength;
					idx2 = idx;
					idx2(value) = idx2(value)+1;
					idx2(value+1) = idx2(value+1)-1;
					o.r.stimuli(idx) = o.r.stimuli(idx2);
					set(handles.OKStimList,'Value',value+1);
					o.modifyStimulus;
				end
			end
		end

		% Button pushed function: OKStimulusRunAllBenchmark
		function OKStimulusRunAllBenchmark_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKStimulusRunAllBenchmark (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.stimuli,'metaStimulus') && o.r.stimuli.n > 0
					o.r.stimuli.choice = [];
					if isa(o.r.screen,'screenManager')
						run(o.r.stimuli, true, 5, o.r.screen);
					else
						run(o.r.stimuli, true);
					end
				end
			end
		end

		% Button pushed function: OKStimulusRunAll
		function OKStimulusRunAll_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKStimulusRunAll (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.stimuli,'metaStimulus') && o.r.stimuli.n > 0
					o.r.stimuli.choice = [];
					if isa(o.r.screen,'screenManager')
						run(o.r.stimuli, [], [], o.r.screen);
					else
						run(o.r.stimuli);
					end
				end
			end
		end

		% Button pushed function: OKStimulusRunBenchmark
		function OKStimulusRunBenchmark_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKStimulusRunBenchmark (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v = get(handles.OKStimList,'Value');
				if v > 0 && isa(o.r.stimuli{v},'baseStimulus')
					run(o.r.stimuli{v}, true, [], o.r.screen);
				end
			end
		end

		% Button pushed function: OKStimulusRunSingle
		function OKStimulusRunSingle_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKStimulusRunSingle (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.stimuli,'metaStimulus') && o.r.stimuli.n > 0
					o.r.stimuli.choice = [];
					runSingle(o.r.stimuli, o.r.screen);
				end
			end
		end

		% Button pushed function: OKStimulusRun
		function OKStimulusRun_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKStimulusRun (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				v = get(handles.OKStimList,'Value');
				if v > 0 && isobject(o.r.stimuli{v})
					run(o.r.stimuli{v}, false, [], o.r.screen);
				end
				set(handles.OKStimulusRunAll,'Enable','on');
			end
		end

		% Button pushed function: OKStimulusUp
		function OKStimulusUp_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				value = get(handles.OKStimList,'Value'); %where are we in the stimulus list
				slength = o.r.stimuli.n;
				if value > 1
					idx = 1:slength;
					idx2 = idx;
					idx2(value) = idx2(value)-1;
					idx2(value-1) = idx2(value-1)+1;
					o.r.stimuli(idx) = o.r.stimuli(idx2);
					set(handles.OKStimList,'Value',value-1);
					o.modifyStimulus;
				end
			end
		end

		% Clicked callback: OKToggleUI
		function OKToggleUI_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToggleUI (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if strcmp(get(handles.OKPanelGlobal,'Visible'),'on')

				set(handles.OKPanelGlobal,'Visible','off');
				set(handles.OKPanelProtocols,'Visible','on');
				set(handles.OKPanelTraining,'Visible','off');

			elseif strcmp(get(handles.OKPanelProtocols,'Visible'),'on')

				set(handles.OKPanelProtocols,'Visible','off')
				set(handles.OKPanelGlobal,'Visible','off')
				set(handles.OKPanelTraining,'Visible','on')
				if isappdata(handles.output,'o')
					o = getappdata(handles.output,'o');
					o.getStateInfo();
				end

			else

				set(handles.OKPanelProtocols,'Visible','off')
				set(handles.OKPanelGlobal,'Visible','on')
				set(handles.OKPanelTraining,'Visible','off')

			end
		end

		% Clicked callback: OKToolbarAbort
		function OKToolbarAbort_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarAbort (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.uiCommand='abort';
				if isa(o.oc,'dataConnection')
					o.oc.write('--abort--');
				end
				drawnow;
			end
		end

		% Clicked callback: OKToolbarDefinePath
		function OKToolbarDefinePath_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarDefinePath (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				path = uigetdir;
				if ischar(path) && exist(path,'dir')
					initialiseSave(o, path);
					if isa(o.r,'runExperiment');
						initialiseSave(o.r, path);
					end
				end
			end
		end

		% Clicked callback: OKToolbarInitialise
		function OKToolbarInitialise_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarInitialise (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r = [];
				o.store.evnt = [];
				o.store = rmfield(o.store,'evnt');
				o.store.visibleStimulus=[];
				o.store = rmfield(o.store,'visibleStimulus');
				o.clearStimulusList;
				o.clearVariableList;
				o.getScreenVals;
				o.getTaskVals;
			end
		end

		% Clicked callback: OKToolbarOpen
		function OKToolbarOpen_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarOpen (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('loadProtocol','1');
			end
		end

		% Clicked callback: OKToolbarRun
		function OKToolbarRun_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarRun (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if ~isempty(o.oc) && o.oc.isOpen == 1 && o.r.useLabJack == 1
					o.oc.write('--GO!--');
					pause(0.5);
				end
				drawnow;
				o.r.uiCommand='run';
				o.r.run;
			end
		end

		% Clicked callback: OKToolbarSave
		function OKToolbarSave_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarSave (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.router('saveProtocol');
			end
		end

		% Clicked callback: OKToolbarStop
		function OKToolbarStop_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarStop (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.r.uiCommand='stop';
				drawnow;
			end
		end

		% On callback: OKToolbarToggleRemote
		function OKToolbarToggleRemote_OnCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarToggleRemote (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				eval(o.store.serverCommand)
			end
		end

		% Value changed function: OKUseGamma
		function OKUseGamma_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKUsePhotoDiode
		function OKUsePhotoDiode_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Button pushed function: OKVariablesLinear
		function OKVariablesLinear_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			values = str2num(get(handles.OKVariableValues,'String'));
			if length(values) == 3
				values=linspace(values(1),values(2),values(3));
				string = num2str(values);
			else
				string = num2str(values);
				string = regexprep(string,'\s+',' '); %collapse spaces
			end
			set(handles.OKVariableValues,'String',string);
		end

		% Button pushed function: OKVariablesLog
		function OKVariablesLog_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			values = str2num(get(handles.OKVariableValues,'String'));
			if length(values) == 3
				values=logspace(log10(values(1)),log10(values(2)),values(3));
				string = num2str(values);
				string = regexprep(string,'\s+',' '); %collapse spaces
				set(handles.OKVariableValues,'String',string);
			else
				warndlg('Please enter a minimum, maximum and number of values');
			end
		end

		% Value changed function: OKVerbose
		function OKVerbose_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKWindowSize
		function OKWindowSize_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKbackgroundColour
		function OKbackgroundColour_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKbenchmark
		function OKbenchmark_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKbenchmark (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			% Hint: get(hObject,'Value') returns toggle state of OKbenchmark
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.screen,'screenManager')
					if get(hObject,'Value') == 1
						set(handles.OKlogFrames,'Value',0)
					end
					o.getScreenVals;
				end
			end
		end

		% Value changed function: OKbitDepth
		function OKbitDepth_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKisTime
		function OKisTime_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Value changed function: OKitTime
		function OKitTime_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Value changed function: OKlogFrames
		function OKlogFrames_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.screen,'screenManager')
					if get(hObject,'Value') == 1
						set(handles.OKbenchmark,'Value',0)
					end
					o.getScreenVals;
				end
			end
		end

		% Value changed function: OKnBlocks
		function OKnBlocks_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Value changed function: OKpixelsPerCm
		function OKpixelsPerCm_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKrandomGenerator
		function OKrandomGenerator_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKrandomGenerator (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			% Hints: contents = cellstr(get(hObject,'String')) returns OKrandomGenerator contents as cell array
			%        contents{get(hObject,'Value')} returns selected item from OKrandomGenerator
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Value changed function: OKrealTime
		function OKrealTime_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Value changed function: OKrecordMovie
		function OKrecordMovie_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKscreenXOffset
		function OKscreenXOffset_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKscreenYOffset
		function OKscreenYOffset_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Value changed function: OKtrialTime
		function OKtrialTime_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getTaskVals;
			end
		end

		% Menu selected function: OKuseDataPixx
		function OKuseDataPixx_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if get(hObject,'Value') == 1 && get(handles.OKuseDataPixx,'Value') == 1
					set(handles.OKuseLabJack,'Value', 0)
				end
				o.getScreenVals;
			end
		end

		% Menu selected function: OKuseEyeLink
		function OKuseEyeLink_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				o.getScreenVals;
			end
		end

		% Menu selected function: OKuseLabJack
		function OKuseLabJack_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if get(hObject,'Value') == 1 && get(handles.OKuseDataPixx,'Value') == 1
					set(handles.OKuseDataPixx,'Value', 0)
				end
				o.getScreenVals;
			end
		end

		% Value changed function: OKverbosityLevel
		function OKverbosityLevel_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKverbosityLevel (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)

			% Hints: get(hObject,'String') returns contents of OKverbosityLevel as text
			%        str2double(get(hObject,'String')) returns contents of OKverbosityLevel as a double
			if isappdata(handles.output,'o')
				o = getappdata(handles.output,'o');
				if isa(o.r.screen,'screenManager')
					o.r.screen.verbosityLevel = str2double(get(hObject,'String'));
				end
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.OKRoot = uifigure('Visible', 'off');
			app.OKRoot.Name = 'Opticka';
			app.OKRoot.Tag = 'OKRoot';
			app.OKMenuFile = uimenu(app.OKRoot);
			app.OKMenuFile.Text = 'File';
			app.OKMenuFile.Tag = 'OKMenuFile';
			app.OKMenuNewProtocol = uimenu(app.OKMenuFile);
			app.OKMenuNewProtocol.MenuSelectedFcn = createCallbackFcn(app, @OKMenuNewProtocol_Callback, true);
			app.OKMenuNewProtocol.Accelerator = 'N';
			app.OKMenuNewProtocol.Text = 'New Protocol';
			app.OKMenuNewProtocol.Tag = 'OKMenuNewProtocol';
			app.OKMenuOpen = uimenu(app.OKMenuFile);
			app.OKMenuOpen.MenuSelectedFcn = createCallbackFcn(app, @OKMenuOpen_Callback, true);
			app.OKMenuOpen.Accelerator = 'O';
			app.OKMenuOpen.Text = 'Open Protocol...';
			app.OKMenuOpen.Tag = 'OKMenuOpen';
			app.OKMenuSave = uimenu(app.OKMenuFile);
			app.OKMenuSave.MenuSelectedFcn = createCallbackFcn(app, @OKMenuSave_Callback, true);
			app.OKMenuSave.Accelerator = 'V';
			app.OKMenuSave.Text = 'Save Protocol...';
			app.OKMenuSave.Tag = 'OKMenuSave';
			app.OKMenuStateInfo = uimenu(app.OKMenuFile);
			app.OKMenuStateInfo.MenuSelectedFcn = createCallbackFcn(app, @OKMenuStateInfo_Callback, true);
			app.OKMenuStateInfo.Separator = 'on';
			app.OKMenuStateInfo.Accelerator = 'I';
			app.OKMenuStateInfo.Text = 'Open stateMachine info...';
			app.OKMenuStateInfo.Tag = 'OKMenuStateInfo';
			app.OKMenuQuit = uimenu(app.OKMenuFile);
			app.OKMenuQuit.MenuSelectedFcn = createCallbackFcn(app, @OKMenuQuit_Callback, true);
			app.OKMenuQuit.Separator = 'on';
			app.OKMenuQuit.Accelerator = 'X';
			app.OKMenuQuit.Text = 'Quit Opticka';
			app.OKMenuQuit.Tag = 'OKMenuQuit';
			app.OKMenuEdit = uimenu(app.OKRoot);
			app.OKMenuEdit.Text = 'Edit';
			app.OKMenuEdit.Tag = 'OKMenuEdit';
			app.OKMenuCut = uimenu(app.OKMenuEdit);
			app.OKMenuCut.Enable = 'off';
			app.OKMenuCut.Text = 'Cut';
			app.OKMenuCut.Tag = 'OKMenuCut';
			app.OKMenuCopy = uimenu(app.OKMenuEdit);
			app.OKMenuCopy.Enable = 'off';
			app.OKMenuCopy.Text = 'Copy';
			app.OKMenuCopy.Tag = 'OKMenuCopy';
			app.OKMenuPaste = uimenu(app.OKMenuEdit);
			app.OKMenuPaste.Enable = 'off';
			app.OKMenuPaste.Text = 'Paste';
			app.OKMenuPaste.Tag = 'OKMenuPaste';
			app.OKMenuTools = uimenu(app.OKRoot);
			app.OKMenuTools.Text = 'Stimulus';
			app.OKMenuTools.Tag = 'OKMenuTools';
			app.OKMenuGrating = uimenu(app.OKMenuTools);
			app.OKMenuGrating.MenuSelectedFcn = createCallbackFcn(app, @OKMenuGrating_Callback, true);
			app.OKMenuGrating.Accelerator = 'G';
			app.OKMenuGrating.Text = 'Grating';
			app.OKMenuGrating.Tag = 'OKMenuGrating';
			app.OKMenuGabor = uimenu(app.OKMenuTools);
			app.OKMenuGabor.MenuSelectedFcn = createCallbackFcn(app, @OKMenuGabor_Callback, true);
			app.OKMenuGabor.Accelerator = 'R';
			app.OKMenuGabor.Text = 'Gabor';
			app.OKMenuGabor.Tag = 'OKMenuGabor';
			app.OKMenuBar = uimenu(app.OKMenuTools);
			app.OKMenuBar.MenuSelectedFcn = createCallbackFcn(app, @OKMenuBar_Callback, true);
			app.OKMenuBar.Accelerator = 'B';
			app.OKMenuBar.Text = 'Bar';
			app.OKMenuBar.Tag = 'OKMenuBar';
			app.OKMenuDots = uimenu(app.OKMenuTools);
			app.OKMenuDots.MenuSelectedFcn = createCallbackFcn(app, @OKMenuDots_Callback, true);
			app.OKMenuDots.Accelerator = 'D';
			app.OKMenuDots.Text = 'Coherent Dots';
			app.OKMenuDots.Tag = 'OKMenuDots';
			app.OKMenuNDots = uimenu(app.OKMenuTools);
			app.OKMenuNDots.MenuSelectedFcn = createCallbackFcn(app, @OKMenuNDots_Callback, true);
			app.OKMenuNDots.Accelerator = 'Q';
			app.OKMenuNDots.Text = 'Newsome Dots';
			app.OKMenuNDots.Tag = 'OKMenuNDots';
			app.OKMenuSpot = uimenu(app.OKMenuTools);
			app.OKMenuSpot.MenuSelectedFcn = createCallbackFcn(app, @OKMenuSpot_Callback, true);
			app.OKMenuSpot.Accelerator = 'S';
			app.OKMenuSpot.Text = 'Spot';
			app.OKMenuSpot.Tag = 'OKMenuSpot';
			app.OKMenuTexture = uimenu(app.OKMenuTools);
			app.OKMenuTexture.MenuSelectedFcn = createCallbackFcn(app, @OKMenuTexture_Callback, true);
			app.OKMenuTexture.Accelerator = 'T';
			app.OKMenuTexture.Text = 'Texture';
			app.OKMenuTexture.Tag = 'OKMenuTexture';
			app.OKMenuTargetInducer = uimenu(app.OKMenuTools);
			app.OKMenuTargetInducer.MenuSelectedFcn = createCallbackFcn(app, @OKMenuTargetInducer_Callback, true);
			app.OKMenuTargetInducer.Accelerator = 'T';
			app.OKMenuTargetInducer.Text = 'Target Inducer';
			app.OKMenuTargetInducer.Tag = 'OKMenuTargetInducer';
			app.OKMenuPlaid = uimenu(app.OKMenuTools);
			app.OKMenuPlaid.Enable = 'off';
			app.OKMenuPlaid.Separator = 'on';
			app.OKMenuPlaid.Text = 'Plaid';
			app.OKMenuPlaid.Tag = 'OKMenuPlaid';
			app.OKMenuNoiseTexture = uimenu(app.OKMenuTools);
			app.OKMenuNoiseTexture.MenuSelectedFcn = createCallbackFcn(app, @OKMenuNoiseTexture_Callback, true);
			app.OKMenuNoiseTexture.Enable = 'off';
			app.OKMenuNoiseTexture.Text = 'Noise Texture';
			app.OKMenuNoiseTexture.Tag = 'OKMenuNoiseTexture';
			app.OKMenuLineTexture = uimenu(app.OKMenuTools);
			app.OKMenuLineTexture.Enable = 'off';
			app.OKMenuLineTexture.Text = 'Line Texture';
			app.OKMenuLineTexture.Tag = 'OKMenuLineTexture';
			app.Untitled_17 = uimenu(app.OKMenuTools);
			app.Untitled_17.Enable = 'off';
			app.Untitled_17.Separator = 'on';
			app.Untitled_17.Text = 'Reverse Correlation';
			app.Untitled_17.Tag = 'Untitled_17';
			app.OKRunRFMapper = uimenu(app.OKMenuTools);
			app.OKRunRFMapper.MenuSelectedFcn = createCallbackFcn(app, @OKRunRFMapper_Callback, true);
			app.OKRunRFMapper.Separator = 'on';
			app.OKRunRFMapper.Accelerator = '1';
			app.OKRunRFMapper.Text = 'Run RF Mapper';
			app.OKRunRFMapper.Tag = 'OKRunRFMapper';
			app.OKRunTrainingSession = uimenu(app.OKMenuTools);
			app.OKRunTrainingSession.Accelerator = '2';
			app.OKRunTrainingSession.Text = 'Run Training Session';
			app.OKRunTrainingSession.Tag = 'OKRunTrainingSession';
			app.OKRunExperiment = uimenu(app.OKMenuTools);
			app.OKRunExperiment.Accelerator = '3';
			app.OKRunExperiment.Text = 'Run Randomised Experiment';
			app.OKRunExperiment.Tag = 'OKRunExperiment';
			app.OKMenuIO = uimenu(app.OKRoot);
			app.OKMenuIO.Text = 'Input Output';
			app.OKMenuIO.Tag = 'OKMenuIO';
			app.OKuseEyeLink = uimenu(app.OKMenuIO);
			app.OKuseEyeLink.MenuSelectedFcn = createCallbackFcn(app, @OKuseEyeLink_Callback, true);
			app.OKuseEyeLink.Checked = 'on';
			app.OKuseEyeLink.Text = 'Use Eyelink (MOC)';
			app.OKuseEyeLink.Tag = 'OKuseEyeLink';
			app.OKuseDataPixx = uimenu(app.OKMenuIO);
			app.OKuseDataPixx.MenuSelectedFcn = createCallbackFcn(app, @OKuseDataPixx_Callback, true);
			app.OKuseDataPixx.Checked = 'on';
			app.OKuseDataPixx.Text = 'Use DataPixx';
			app.OKuseDataPixx.Tag = 'OKuseDataPixx';
			app.OKuseLabJack = uimenu(app.OKMenuIO);
			app.OKuseLabJack.MenuSelectedFcn = createCallbackFcn(app, @OKuseLabJack_Callback, true);
			app.OKuseLabJack.Checked = 'on';
			app.OKuseLabJack.Text = 'Use LabJack';
			app.OKuseLabJack.Tag = 'OKuseLabJack';
			app.OKMenuPreferences = uimenu(app.OKRoot);
			app.OKMenuPreferences.Text = 'Calibration';
			app.OKMenuPreferences.Tag = 'OKMenuPreferences';
			app.OKMenuCalibrateLuminance = uimenu(app.OKMenuPreferences);
			app.OKMenuCalibrateLuminance.MenuSelectedFcn = createCallbackFcn(app, @OKMenuCalibrateLuminance_Callback, true);
			app.OKMenuCalibrateLuminance.Separator = 'on';
			app.OKMenuCalibrateLuminance.Text = 'Calibrate Gamma...';
			app.OKMenuCalibrateLuminance.Tag = 'OKMenuCalibrateLuminance';
			app.OKMenuCalibrateSize = uimenu(app.OKMenuPreferences);
			app.OKMenuCalibrateSize.MenuSelectedFcn = createCallbackFcn(app, @OKMenuCalibrateSize_Callback, true);
			app.OKMenuCalibrateSize.Text = 'Calibrate Size...';
			app.OKMenuCalibrateSize.Tag = 'OKMenuCalibrateSize';
			app.OKMenuLoadGamma = uimenu(app.OKMenuPreferences);
			app.OKMenuLoadGamma.MenuSelectedFcn = createCallbackFcn(app, @OKMenuLoadGamma_Callback, true);
			app.OKMenuLoadGamma.Separator = 'on';
			app.OKMenuLoadGamma.Text = 'Load Gamma...';
			app.OKMenuLoadGamma.Tag = 'OKMenuLoadGamma';
			app.OKMenuSaveGamma = uimenu(app.OKMenuPreferences);
			app.OKMenuSaveGamma.MenuSelectedFcn = createCallbackFcn(app, @OKMenuSaveGamma_Callback, true);
			app.OKMenuSaveGamma.Text = 'Save Gamma...';
			app.OKMenuSaveGamma.Tag = 'OKMenuSaveGamma';
			app.OKMenumanageCode = uimenu(app.OKMenuPreferences);
			app.OKMenumanageCode.MenuSelectedFcn = createCallbackFcn(app, @OKMenumanageCode_Callback, true);
			app.OKMenumanageCode.Enable = 'off';
			app.OKMenumanageCode.Separator = 'on';
			app.OKMenumanageCode.Text = 'Check for Updates';
			app.OKMenumanageCode.Tag = 'OKMenumanageCode';
			app.OKMenuEditConfiguration = uimenu(app.OKMenuPreferences);
			app.OKMenuEditConfiguration.Enable = 'off';
			app.OKMenuEditConfiguration.Separator = 'on';
			app.OKMenuEditConfiguration.Text = 'Edit Configuration';
			app.OKMenuEditConfiguration.Tag = 'OKMenuEditConfiguration';
			app.OKMenuLogs = uimenu(app.OKRoot);
			app.OKMenuLogs.Text = 'Logs';
			app.OKMenuLogs.Tag = 'OKMenuLogs';
			app.OKMenuAllTimingLogs = uimenu(app.OKMenuLogs);
			app.OKMenuAllTimingLogs.MenuSelectedFcn = createCallbackFcn(app, @OKMenuAllTimingLogs_Callback, true);
			app.OKMenuAllTimingLogs.Text = 'All Timing Logs';
			app.OKMenuAllTimingLogs.Tag = 'OKMenuAllTimingLogs';
			app.OKMenuStimulusLog = uimenu(app.OKMenuLogs);
			app.OKMenuStimulusLog.MenuSelectedFcn = createCallbackFcn(app, @OKMenuStimulusLog_Callback, true);
			app.OKMenuStimulusLog.Text = 'Stimulus Sequence';
			app.OKMenuStimulusLog.Tag = 'OKMenuStimulusLog';
			app.OKMenuShowGammaPlots = uimenu(app.OKMenuLogs);
			app.OKMenuShowGammaPlots.MenuSelectedFcn = createCallbackFcn(app, @OKMenuShowGammaPlots_Callback, true);
			app.OKMenuShowGammaPlots.Text = 'Gamma Correction Plots';
			app.OKMenuShowGammaPlots.Tag = 'OKMenuShowGammaPlots';
			app.OKMenurfMapperLog = uimenu(app.OKMenuLogs);
			app.OKMenurfMapperLog.Enable = 'off';
			app.OKMenurfMapperLog.Separator = 'on';
			app.OKMenurfMapperLog.Text = 'RF Mapper Log';
			app.OKMenurfMapperLog.Tag = 'OKMenurfMapperLog';
			app.OKToolbar = uitoolbar(app.OKRoot);
			app.OKToolbar.Tag = 'OKToolbar';
			app.OKToolbarInitialise = uipushtool(app.OKToolbar);
			app.OKToolbarInitialise.Tag = 'OKToolbarInitialise';
			app.OKToolbarInitialise.Tooltip = 'Initialise Opticka with a fresh set of settings';
			app.OKToolbarInitialise.ClickedCallback = createCallbackFcn(app, @OKToolbarInitialise_ClickedCallback, true);
			app.OKToolbarInitialise.Icon = 'OKToolbarInitialise_image.png';
			app.OKToolbarOpen = uipushtool(app.OKToolbar);
			app.OKToolbarOpen.Tag = 'OKToolbarOpen';
			app.OKToolbarOpen.Tooltip = 'Open File';
			app.OKToolbarOpen.ClickedCallback = createCallbackFcn(app, @OKToolbarOpen_ClickedCallback, true);
			app.OKToolbarOpen.Icon = 'OKToolbarOpen_image.png';
			app.OKToolbarSave = uipushtool(app.OKToolbar);
			app.OKToolbarSave.Tag = 'OKToolbarSave';
			app.OKToolbarSave.Tooltip = 'Save last run';
			app.OKToolbarSave.ClickedCallback = createCallbackFcn(app, @OKToolbarSave_ClickedCallback, true);
			app.OKToolbarSave.Icon = 'OKToolbarSave_image.png';
			app.OKToolbarDefinePath = uipushtool(app.OKToolbar);
			app.OKToolbarDefinePath.Tag = 'OKToolbarDefinePath';
			app.OKToolbarDefinePath.Tooltip = 'Choose a folder to save eyelink and other log files';
			app.OKToolbarDefinePath.ClickedCallback = createCallbackFcn(app, @OKToolbarDefinePath_ClickedCallback, true);
			app.OKToolbarDefinePath.Icon = 'OKToolbarDefinePath_image.png';
			app.OKToolbarDefinePath.Separator = 'on';
			app.OKToggleUI = uipushtool(app.OKToolbar);
			app.OKToggleUI.Tag = 'OKToggleUI';
			app.OKToggleUI.Tooltip = 'Toggle settings panels...';
			app.OKToggleUI.ClickedCallback = createCallbackFcn(app, @OKToggleUI_ClickedCallback, true);
			app.OKToggleUI.Icon = 'OKToggleUI_image.png';
			app.OKToggleUI.Separator = 'on';
			app.OKRFMapper = uipushtool(app.OKToolbar);
			app.OKRFMapper.Tag = 'OKRFMapper';
			app.OKRFMapper.Tooltip = 'Start the RF Mapper';
			app.OKRFMapper.ClickedCallback = createCallbackFcn(app, @OKRFMapper_ClickedCallback, true);
			app.OKRFMapper.Icon = 'OKRFMapper_image.png';
			app.OKRFMapper.Separator = 'on';
			app.OKStartTask = uipushtool(app.OKToolbar);
			app.OKStartTask.Tag = 'OKStartTask';
			app.OKStartTask.Tooltip = 'Start a behavioural task...';
			app.OKStartTask.ClickedCallback = createCallbackFcn(app, @OKStartTask_ClickedCallback, true);
			app.OKStartTask.Icon = 'OKStartTask_image.png';
			app.OKToolbarRun = uipushtool(app.OKToolbar);
			app.OKToolbarRun.Tag = 'OKToolbarRun';
			app.OKToolbarRun.Tooltip = 'Start a MOC Task';
			app.OKToolbarRun.ClickedCallback = createCallbackFcn(app, @OKToolbarRun_ClickedCallback, true);
			app.OKToolbarRun.Icon = 'OKToolbarRun_image.png';
			app.OKToolbarStop = uipushtool(app.OKToolbar);
			app.OKToolbarStop.Tag = 'OKToolbarStop';
			app.OKToolbarStop.Tooltip = 'STOP';
			app.OKToolbarStop.ClickedCallback = createCallbackFcn(app, @OKToolbarStop_ClickedCallback, true);
			app.OKToolbarStop.Enable = 'off';
			app.OKToolbarStop.Icon = 'OKToolbarStop_image.png';
			app.OKToolbarAbort = uipushtool(app.OKToolbar);
			app.OKToolbarAbort.Tag = 'OKToolbarAbort';
			app.OKToolbarAbort.Tooltip = 'ABORT';
			app.OKToolbarAbort.ClickedCallback = createCallbackFcn(app, @OKToolbarAbort_ClickedCallback, true);
			app.OKToolbarAbort.Enable = 'off';
			app.OKToolbarAbort.Icon = 'OKToolbarAbort_image.png';
			app.OKPanelTellOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelTellOmniplex.Tag = 'OKPanelTellOmniplex';
			app.OKPanelTellOmniplex.Tooltip = 'Connect to Omniplex!';
			app.OKPanelTellOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelTellOmniplex_ClickedCallback, true);
			app.OKPanelTellOmniplex.Enable = 'off';
			app.OKPanelTellOmniplex.Icon = 'OKPanelTellOmniplex_image.png';
			app.OKPanelTellOmniplex.Separator = 'on';
			app.OKPanelReconnectOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelReconnectOmniplex.Tag = 'OKPanelReconnectOmniplex';
			app.OKPanelReconnectOmniplex.Tooltip = 'Reconnect to Omniplex!';
			app.OKPanelReconnectOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelReconnectOmniplex_ClickedCallback, true);
			app.OKPanelReconnectOmniplex.Enable = 'off';
			app.OKPanelReconnectOmniplex.Icon = 'OKPanelReconnectOmniplex_image.png';
			app.OKPanelSendOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelSendOmniplex.Tag = 'OKPanelSendOmniplex';
			app.OKPanelSendOmniplex.Tooltip = 'Send Omniplex Stimulus';
			app.OKPanelSendOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelSendOmniplex_ClickedCallback, true);
			app.OKPanelSendOmniplex.Enable = 'off';
			app.OKPanelSendOmniplex.Icon = 'OKPanelSendOmniplex_image.png';
			app.OKPanelPingOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelPingOmniplex.Tag = 'OKPanelPingOmniplex';
			app.OKPanelPingOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelPingOmniplex_ClickedCallback, true);
			app.OKPanelPingOmniplex.Enable = 'off';
			app.OKPanelPingOmniplex.Icon = 'OKPanelPingOmniplex_image.png';
			app.OKPanelDisconnectOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelDisconnectOmniplex.Tag = 'OKPanelDisconnectOmniplex';
			app.OKPanelDisconnectOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelDisconnectOmniplex_ClickedCallback, true);
			app.OKPanelDisconnectOmniplex.Enable = 'off';
			app.OKPanelDisconnectOmniplex.Icon = 'OKPanelDisconnectOmniplex_image.png';
			app.OKToolbarToggleRemote = uitoggletool(app.OKToolbar);
			app.OKToolbarToggleRemote.Tag = 'OKToolbarToggleRemote';
			app.OKToolbarToggleRemote.Tooltip = 'Toggle into remote client mode';
			app.OKToolbarToggleRemote.Enable = 'off';
			app.OKToolbarToggleRemote.Icon = 'OKToolbarToggleRemote_image.png';
			app.OKToolbarToggleRemote.Separator = 'on';
			app.OKToolbarToggleRemote.OnCallback = createCallbackFcn(app, @OKToolbarToggleRemote_OnCallback, true);
			app.OKPanelIndividual = uibuttongroup(app.OKRoot);
			app.OKPanelIndividual.Title = 'Individual Options';
			app.OKPanelIndividual.Tag = 'OKPanelIndividual';
			app.OKPanelStimulusList = uibuttongroup(app.OKPanelIndividual);
			app.OKPanelStimulusList.Title = 'Stimulus List';
			app.OKPanelStimulusList.Tag = 'OKPanelStimulusList';
			app.OKAddStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKAddStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKAddStimulus_Callback, true);
			app.OKAddStimulus.Tag = 'OKAddStimulus';
			app.OKAddStimulus.Text = 'Add';
			app.OKDeleteStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKDeleteStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKDeleteStimulus_Callback, true);
			app.OKDeleteStimulus.Tag = 'OKDeleteStimulus';
			app.OKDeleteStimulus.Enable = 'off';
			app.OKDeleteStimulus.Text = 'Delete';
			app.OKCopyStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKCopyStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKCopyStimulus_Callback, true);
			app.OKCopyStimulus.Tag = 'OKCopyStimulus';
			app.OKCopyStimulus.Enable = 'off';
			app.OKCopyStimulus.Text = 'Copy';
			app.OKInspectStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKInspectStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKInspectStimulus_Callback, true);
			app.OKInspectStimulus.Tag = 'OKInspectStimulus';
			app.OKInspectStimulus.Enable = 'off';
			app.OKInspectStimulus.Text = '?';
			app.OKModifyStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKModifyStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKModifyStimulus_Callback, true);
			app.OKModifyStimulus.Tag = 'OKModifyStimulus';
			app.OKModifyStimulus.Enable = 'off';
			app.OKModifyStimulus.Text = 'Modify / Examine';
			app.OKStimulusDown = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusDown.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusDown_Callback, true);
			app.OKStimulusDown.Tag = 'OKStimulusDown';
			app.OKStimulusDown.Enable = 'off';
			app.OKStimulusDown.Text = '<html>&darr;</html>';
			app.OKStimulusUp = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusUp.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusUp_Callback, true);
			app.OKStimulusUp.Tag = 'OKStimulusUp';
			app.OKStimulusUp.Enable = 'off';
			app.OKStimulusUp.Text = '<html>&uarr;</html>';
			app.OKStimulusRun = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRun.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRun_Callback, true);
			app.OKStimulusRun.Tag = 'OKStimulusRun';
			app.OKStimulusRun.Enable = 'off';
			app.OKStimulusRun.Tooltip = 'Run the selected stimulus in a window';
			app.OKStimulusRun.Text = 'R';
			app.OKStimulusRunBenchmark = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunBenchmark.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunBenchmark_Callback, true);
			app.OKStimulusRunBenchmark.Tag = 'OKStimulusRunBenchmark';
			app.OKStimulusRunBenchmark.Enable = 'off';
			app.OKStimulusRunBenchmark.Tooltip = 'Run the selected stimulus and benchmark maximum FPS';
			app.OKStimulusRunBenchmark.Text = 'BR';
			app.OKStimulusRunAll = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunAll.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunAll_Callback, true);
			app.OKStimulusRunAll.Tag = 'OKStimulusRunAll';
			app.OKStimulusRunAll.Enable = 'off';
			app.OKStimulusRunAll.Tooltip = 'Preview All Stimuli...';
			app.OKStimulusRunAll.Text = 'A';
			app.OKStimulusRunAllBenchmark = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunAllBenchmark.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunAllBenchmark_Callback, true);
			app.OKStimulusRunAllBenchmark.Tag = 'OKStimulusRunAllBenchmark';
			app.OKStimulusRunAllBenchmark.Enable = 'off';
			app.OKStimulusRunAllBenchmark.Tooltip = 'Benchmark All Stimuli...';
			app.OKStimulusRunAllBenchmark.Text = 'BA';
			app.OKStimList = uilistbox(app.OKPanelStimulusList);
			app.OKStimList.Items = {};
			app.OKStimList.ValueChangedFcn = createCallbackFcn(app, @OKStimList_Callback, true);
			app.OKStimList.Tag = 'OKStimList';
			app.OKStimList.Value = {};
			app.OKStimulusRunSingle = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunSingle.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunSingle_Callback, true);
			app.OKStimulusRunSingle.Tag = 'OKStimulusRunSingle';
			app.OKStimulusRunSingle.Enable = 'off';
			app.OKStimulusRunSingle.Tooltip = 'Single run using eyetracker';
			app.OKStimulusRunSingle.Text = 'RS';
			app.OKOmniplexStatus = uilabel(app.OKPanelIndividual);
			app.OKOmniplexStatus.Tag = 'OKOmniplexStatus';
			app.OKOmniplexStatus.Tooltip = 'This connection status is for the online display ONLY, it does not affect data collection communication via the datapixx';
			app.OKOmniplexStatus.Text = 'OPX Server for Online PSTH: disconnected';
			app.OKOptickaVersion = uilabel(app.OKPanelIndividual);
			app.OKOptickaVersion.Tag = 'OKOptickaVersion';
			app.OKOptickaVersion.Text = 'Loading...';
			app.OKPanelVariables = uipanel(app.OKPanelIndividual);
			app.OKPanelVariables.Title = 'Independent Variables';
			app.OKPanelVariables.Tag = 'OKPanelVariables';
			app.text140 = uilabel(app.OKPanelVariables);
			app.text140.Tag = 'text140';
			app.text140.Text = 'Affects Stimuli';
			app.text141 = uilabel(app.OKPanelVariables);
			app.text141.Tag = 'text141';
			app.text141.Text = 'Variable Values';
			app.text142 = uilabel(app.OKPanelVariables);
			app.text142.Tag = 'text142';
			app.text142.Text = 'Variable Name';
			app.OKCopyVariableName = uibutton(app.OKPanelVariables, 'push');
			app.OKCopyVariableName.ButtonPushedFcn = createCallbackFcn(app, @OKCopyVariableName_Callback, true);
			app.OKCopyVariableName.Tag = 'OKCopyVariableName';
			app.OKCopyVariableName.Text = '<<';
			app.OKVariablesLinear = uibutton(app.OKPanelVariables, 'push');
			app.OKVariablesLinear.ButtonPushedFcn = createCallbackFcn(app, @OKVariablesLinear_Callback, true);
			app.OKVariablesLinear.Tag = 'OKVariablesLinear';
			app.OKVariablesLinear.Text = 'lin';
			app.OKVariablesLog = uibutton(app.OKPanelVariables, 'push');
			app.OKVariablesLog.ButtonPushedFcn = createCallbackFcn(app, @OKVariablesLog_Callback, true);
			app.OKVariablesLog.Tag = 'OKVariablesLog';
			app.OKVariablesLog.Text = 'log';
			app.OKCopyVariableNameValues = uibutton(app.OKPanelVariables, 'push');
			app.OKCopyVariableNameValues.ButtonPushedFcn = createCallbackFcn(app, @OKCopyVariableNameValues_Callback, true);
			app.OKCopyVariableNameValues.Tag = 'OKCopyVariableNameValues';
			app.OKCopyVariableNameValues.Text = '<-';
			app.text178 = uilabel(app.OKPanelVariables);
			app.text178.Tag = 'text178';
			app.text178.Text = 'Stimulus; Offset';
			app.OKVariableStimuli = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableStimuli.Tag = 'OKVariableStimuli';
			app.OKVariableStimuli.Value = '1';
			app.OKVariableValues = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableValues.Tag = 'OKVariableValues';
			app.OKVariableValues.Value = '[-90:45:90]';
			app.OKVariableName = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableName.Tag = 'OKVariableName';
			app.OKVariableName.Value = 'angle';
			app.OKVariableOffset = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableOffset.Tag = 'OKVariableOffset';
			app.OKVariableOffset.Value = '[]';
			app.OKVariableList = uilistbox(app.OKPanelVariables);
			app.OKVariableList.Items = {'xPosition', 'yPosition', 'angle', 'motionAngle', 'size', 'sf', 'tf', 'contrast', 'alpha', 'speed', 'phase', 'startPosition', 'barLength', 'barWidth', 'alpha', 'nDots', 'coherence', 'scale', 'driftDirection'};
			app.OKVariableList.Tag = 'OKVariableList';
			app.OKVariableList.Value = 'xPosition';
			app.uipanel44 = uibuttongroup(app.OKPanelIndividual);
			app.uipanel44.Title = 'Ind. Variable List';
			app.uipanel44.Tag = 'uipanel44';
			app.OKAddVariable = uibutton(app.uipanel44, 'push');
			app.OKAddVariable.ButtonPushedFcn = createCallbackFcn(app, @OKAddVariable_Callback, true);
			app.OKAddVariable.Tag = 'OKAddVariable';
			app.OKAddVariable.Text = 'Add';
			app.OKDeleteVariable = uibutton(app.uipanel44, 'push');
			app.OKDeleteVariable.ButtonPushedFcn = createCallbackFcn(app, @OKDeleteVariable_Callback, true);
			app.OKDeleteVariable.Tag = 'OKDeleteVariable';
			app.OKDeleteVariable.Enable = 'off';
			app.OKDeleteVariable.Text = 'Delete';
			app.OKCopyVariable = uibutton(app.uipanel44, 'push');
			app.OKCopyVariable.ButtonPushedFcn = createCallbackFcn(app, @OKCopyVariable_Callback, true);
			app.OKCopyVariable.Tag = 'OKCopyVariable';
			app.OKCopyVariable.Enable = 'off';
			app.OKCopyVariable.Text = 'Copy';
			app.OKEditVariable = uibutton(app.uipanel44, 'push');
			app.OKEditVariable.ButtonPushedFcn = createCallbackFcn(app, @OKEditVariable_Callback, true);
			app.OKEditVariable.Tag = 'OKEditVariable';
			app.OKEditVariable.Enable = 'off';
			app.OKEditVariable.Text = 'Edit';
			app.OKInspectVariable = uibutton(app.uipanel44, 'push');
			app.OKInspectVariable.ButtonPushedFcn = createCallbackFcn(app, @OKInspectVariable_Callback, true);
			app.OKInspectVariable.Tag = 'OKInspectVariable';
			app.OKInspectVariable.Enable = 'off';
			app.OKInspectVariable.Text = '?';
			app.OKVarList = uilistbox(app.uipanel44);
			app.OKVarList.Items = {};
			app.OKVarList.Tag = 'OKVarList';
			app.OKVarList.Value = {};
			app.OKPanelStimulus = uipanel(app.OKPanelIndividual);
			app.OKPanelStimulus.Tag = 'OKPanelStimulus';
			app.OKPanelStimulusText = uilabel(app.OKPanelStimulus);
			app.OKPanelStimulusText.Tag = 'OKPanelStimulusText';
			app.OKPanelStimulusText.Enable = 'off';
			app.OKPanelStimulusText.Text = 'Stimulus options...';
			app.TabGroup = uitabgroup(app.OKRoot);
			app.DisplayTab = uitab(app.TabGroup);
			app.DisplayTab.Title = 'Display';
			app.uipanel45 = uibuttongroup(app.DisplayTab);
			app.uipanel45.Title = 'Methods of Constants (MOC) Only Settings';
			app.uipanel45.Tag = 'uipanel45';
			app.OKRandomise = uicheckbox(app.uipanel45);
			app.OKRandomise.ValueChangedFcn = createCallbackFcn(app, @OKRandomise_Callback, true);
			app.OKRandomise.Tag = 'OKRandomise';
			app.OKRandomise.Text = 'Randomise Order';
			app.OKRandomise.Value = true;
			app.OKrandomGenerator = uidropdown(app.uipanel45);
			app.OKrandomGenerator.Items = {'mt19937ar', 'mcg16807', 'mrg32k3a', 'swb2712'};
			app.OKrandomGenerator.ValueChangedFcn = createCallbackFcn(app, @OKrandomGenerator_Callback, true);
			app.OKrandomGenerator.Tag = 'OKrandomGenerator';
			app.OKrandomGenerator.Value = 'mt19937ar';
			app.text105 = uilabel(app.uipanel45);
			app.text105.Tag = 'text105';
			app.text105.Text = 'Random Algorithm';
			app.OKRandomSeed = uieditfield(app.uipanel45, 'text');
			app.OKRandomSeed.ValueChangedFcn = createCallbackFcn(app, @OKRandomSeed_Callback, true);
			app.OKRandomSeed.Tag = 'OKRandomSeed';
			app.OKRandomSeed.Tooltip = 'Random seed for the random number generator';
			app.OKRandomSeed.Value = '[]';
			app.text106 = uilabel(app.uipanel45);
			app.text106.Tag = 'text106';
			app.text106.Text = 'Random Seed';
			app.OKnBlocks = uieditfield(app.uipanel45, 'text');
			app.OKnBlocks.ValueChangedFcn = createCallbackFcn(app, @OKnBlocks_Callback, true);
			app.OKnBlocks.Tag = 'OKnBlocks';
			app.OKnBlocks.Value = '5';
			app.OKisTime = uieditfield(app.uipanel45, 'text');
			app.OKisTime.ValueChangedFcn = createCallbackFcn(app, @OKisTime_Callback, true);
			app.OKisTime.Tag = 'OKisTime';
			app.OKisTime.Tooltip = 'Inter-trial time for acute run (not using state machine)';
			app.OKisTime.Value = '0.5';
			app.text9 = uilabel(app.uipanel45);
			app.text9.Tag = 'text9';
			app.text9.Text = 'Trial Blocks';
			app.text10 = uilabel(app.uipanel45);
			app.text10.Tag = 'text10';
			app.text10.Text = 'MOC IT Time';
			app.OKitTime = uieditfield(app.uipanel45, 'text');
			app.OKitTime.ValueChangedFcn = createCallbackFcn(app, @OKitTime_Callback, true);
			app.OKitTime.Tag = 'OKitTime';
			app.OKitTime.Tooltip = 'Inter-block time for acute run (not using state machine)';
			app.OKitTime.Value = '1';
			app.text104 = uilabel(app.uipanel45);
			app.text104.Tag = 'text104';
			app.text104.Text = 'MOC IB Time';
			app.OKtrialTime = uieditfield(app.uipanel45, 'text');
			app.OKtrialTime.ValueChangedFcn = createCallbackFcn(app, @OKtrialTime_Callback, true);
			app.OKtrialTime.Tag = 'OKtrialTime';
			app.OKtrialTime.Tooltip = 'Trial time for acute run (not using state machine)';
			app.OKtrialTime.Value = '2';
			app.text107 = uilabel(app.uipanel45);
			app.text107.Tag = 'text107';
			app.text107.Text = 'MOC Trial Time';
			app.OKrealTime = uicheckbox(app.uipanel45);
			app.OKrealTime.ValueChangedFcn = createCallbackFcn(app, @OKrealTime_Callback, true);
			app.OKrealTime.Tag = 'OKrealTime';
			app.OKrealTime.Tooltip = 'If ON then we use absoloute time which is less liable to error build-up, otherwise measure time in number of frames needed to show, and even if frames are dropped, opticka simply shows the right number of frames ignoring possible time drift (better for record movie mode)';
			app.OKrealTime.Text = 'Use Clock Time?';
			app.OKrealTime.Value = true;
			app.text102 = uilabel(app.DisplayTab);
			app.text102.Tag = 'text102';
			app.text102.Text = 'Anti-Aliasing';
			app.OKMonitorDistance = uieditfield(app.DisplayTab, 'text');
			app.OKMonitorDistance.ValueChangedFcn = createCallbackFcn(app, @OKMonitorDistance_Callback, true);
			app.OKMonitorDistance.Tag = 'OKMonitorDistance';
			app.OKMonitorDistance.Value = '57.3';
			app.OKpixelsPerCm = uieditfield(app.DisplayTab, 'text');
			app.OKpixelsPerCm.ValueChangedFcn = createCallbackFcn(app, @OKpixelsPerCm_Callback, true);
			app.OKpixelsPerCm.Tag = 'OKpixelsPerCm';
			app.OKpixelsPerCm.Tooltip = 'Pixels per cm as calculated during screen calibration';
			app.OKpixelsPerCm.Value = '32';
			app.text3 = uilabel(app.DisplayTab);
			app.text3.Tag = 'text3';
			app.text3.Text = 'Monitor';
			app.text4 = uilabel(app.DisplayTab);
			app.text4.Tag = 'text4';
			app.text4.Text = 'Distance (cm)';
			app.text5 = uilabel(app.DisplayTab);
			app.text5.Tag = 'text5';
			app.text5.Text = 'Px / cm';
			app.OKWindowSize = uieditfield(app.DisplayTab, 'text');
			app.OKWindowSize.ValueChangedFcn = createCallbackFcn(app, @OKWindowSize_Callback, true);
			app.OKWindowSize.Tag = 'OKWindowSize';
			app.OKWindowSize.Tooltip = 'Enter an [] matrix for automatic fullscreen or a [W H] matrix for a widthxheight window';
			app.OKWindowSize.Value = '[]';
			app.OKscreenXOffset = uieditfield(app.DisplayTab, 'text');
			app.OKscreenXOffset.ValueChangedFcn = createCallbackFcn(app, @OKscreenXOffset_Callback, true);
			app.OKscreenXOffset.Tag = 'OKscreenXOffset';
			app.OKscreenXOffset.Value = '0';
			app.OKscreenYOffset = uieditfield(app.DisplayTab, 'text');
			app.OKscreenYOffset.ValueChangedFcn = createCallbackFcn(app, @OKscreenYOffset_Callback, true);
			app.OKscreenYOffset.Tag = 'OKscreenYOffset';
			app.OKscreenYOffset.Value = '0';
			app.text6 = uilabel(app.DisplayTab);
			app.text6.Tag = 'text6';
			app.text6.Text = 'X Center';
			app.text7 = uilabel(app.DisplayTab);
			app.text7.Tag = 'text7';
			app.text7.Text = 'Y Center';
			app.OKOpenGLBlending = uicheckbox(app.DisplayTab);
			app.OKOpenGLBlending.ValueChangedFcn = createCallbackFcn(app, @OKOpenGLBlending_Callback, true);
			app.OKOpenGLBlending.Tag = 'OKOpenGLBlending';
			app.OKOpenGLBlending.Tooltip = 'Enable global OpenGL blending mode in PTB?';
			app.OKOpenGLBlending.Text = 'OpenGL Blending';
			app.text83 = uilabel(app.DisplayTab);
			app.text83.Tag = 'text83';
			app.text83.Text = 'Window Size';
			app.OKSelectScreen = uidropdown(app.DisplayTab);
			app.OKSelectScreen.Items = {'0', '1'};
			app.OKSelectScreen.ValueChangedFcn = createCallbackFcn(app, @OKSelectScreen_Callback, true);
			app.OKSelectScreen.Tag = 'OKSelectScreen';
			app.OKSelectScreen.Value = '0';
			app.text100 = uilabel(app.DisplayTab);
			app.text100.Tag = 'text100';
			app.text100.Text = 'Src Blend';
			app.text101 = uilabel(app.DisplayTab);
			app.text101.Tag = 'text101';
			app.text101.Text = 'Dest blend';
			app.OKHideFlash = uicheckbox(app.DisplayTab);
			app.OKHideFlash.ValueChangedFcn = createCallbackFcn(app, @OKHideFlash_Callback, true);
			app.OKHideFlash.Tag = 'OKHideFlash';
			app.OKHideFlash.Tooltip = 'Use Mario''s gamma trick to hide black flash onset';
			app.OKHideFlash.Text = 'Hide Flash';
			app.OKUsePhotoDiode = uicheckbox(app.DisplayTab);
			app.OKUsePhotoDiode.ValueChangedFcn = createCallbackFcn(app, @OKUsePhotoDiode_Callback, true);
			app.OKUsePhotoDiode.Tag = 'OKUsePhotoDiode';
			app.OKUsePhotoDiode.Tooltip = 'Show a white<->black square to log stimulus onset with a photodiode.';
			app.OKUsePhotoDiode.Text = 'Photodiode';
			app.OKVerbose = uicheckbox(app.DisplayTab);
			app.OKVerbose.ValueChangedFcn = createCallbackFcn(app, @OKVerbose_Callback, true);
			app.OKVerbose.Tag = 'OKVerbose';
			app.OKVerbose.Tooltip = 'Show more information in the command window?';
			app.OKVerbose.Text = 'Verbose';
			app.OKDebug = uicheckbox(app.DisplayTab);
			app.OKDebug.ValueChangedFcn = createCallbackFcn(app, @OKDebug_Callback, true);
			app.OKDebug.Tag = 'OKDebug';
			app.OKDebug.Tooltip = 'Show onscreen info and disregard timing errors';
			app.OKDebug.Text = 'Debug Mode';
			app.OKGLSrc = uidropdown(app.DisplayTab);
			app.OKGLSrc.Items = {'GL_ZERO', 'GL_ONE', 'GL_DST_COLOR', 'GL_ONE_MINUS_DST_COLOR', 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA', 'GL_DST_ALPHA', 'GL_ONE_MINUS_DST_ALPHA', 'GL_SRC_ALPHA_SATURATE'};
			app.OKGLSrc.ValueChangedFcn = createCallbackFcn(app, @OKGLSrc_Callback, true);
			app.OKGLSrc.Tag = 'OKGLSrc';
			app.OKGLSrc.Value = 'GL_ONE';
			app.OKGLDst = uidropdown(app.DisplayTab);
			app.OKGLDst.Items = {'GL_ZERO', 'GL_ONE', 'GL_DST_COLOR', 'GL_ONE_MINUS_DST_COLOR', 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA', 'GL_DST_ALPHA', 'GL_ONE_MINUS_DST_ALPHA', 'GL_SRC_ALPHA_SATURATE'};
			app.OKGLDst.ValueChangedFcn = createCallbackFcn(app, @OKGLDst_Callback, true);
			app.OKGLDst.Tag = 'OKGLDst';
			app.OKGLDst.Value = 'GL_ZERO';
			app.OKAntiAliasing = uieditfield(app.DisplayTab, 'text');
			app.OKAntiAliasing.ValueChangedFcn = createCallbackFcn(app, @OKAntiAliasing_Callback, true);
			app.OKAntiAliasing.Tag = 'OKAntiAliasing';
			app.OKAntiAliasing.Tooltip = 'Global anti-aliasing';
			app.OKAntiAliasing.Value = '4';
			app.OKNativeBeamPosition = uicheckbox(app.DisplayTab);
			app.OKNativeBeamPosition.ValueChangedFcn = createCallbackFcn(app, @OKNativeBeamPosition_Callback, true);
			app.OKNativeBeamPosition.Tag = 'OKNativeBeamPosition';
			app.OKNativeBeamPosition.Tooltip = 'Use the OS native beam position queries or override with PTB routine; override is better for OS X allong with use of the kernel driver';
			app.OKNativeBeamPosition.Text = 'Native BeamPos?';
			app.OKrecordMovie = uicheckbox(app.DisplayTab);
			app.OKrecordMovie.ValueChangedFcn = createCallbackFcn(app, @OKrecordMovie_Callback, true);
			app.OKrecordMovie.Tag = 'OKrecordMovie';
			app.OKrecordMovie.Tooltip = 'Enable PTB stimulus recording, currently buggy as gstreamer not stable';
			app.OKrecordMovie.Text = 'Record Movie';
			app.OKUseGamma = uidropdown(app.DisplayTab);
			app.OKUseGamma.Items = {'None'};
			app.OKUseGamma.ValueChangedFcn = createCallbackFcn(app, @OKUseGamma_Callback, true);
			app.OKUseGamma.Tag = 'OKUseGamma';
			app.OKUseGamma.Value = 'None';
			app.text189 = uilabel(app.DisplayTab);
			app.text189.Tag = 'text189';
			app.text189.Text = 'Gamma Correction';
			app.OKlogFrames = uicheckbox(app.DisplayTab);
			app.OKlogFrames.ValueChangedFcn = createCallbackFcn(app, @OKlogFrames_Callback, true);
			app.OKlogFrames.Tag = 'OKlogFrames';
			app.OKlogFrames.Tooltip = 'Log each frame time to check for dropped frames etc.';
			app.OKlogFrames.Text = 'Log Times';
			app.OKlogFrames.Value = true;
			app.text191 = uilabel(app.DisplayTab);
			app.text191.Tag = 'text191';
			app.text191.Tooltip = 'Bit Depth of backing framebuffer';
			app.text191.Text = 'Bit Depth';
			app.OKbitDepth = uidropdown(app.DisplayTab);
			app.OKbitDepth.Items = {'FloatingPoint32BitIfPossible', 'FloatingPoint32Bit', 'FixedPoint16Bit', 'FloatingPoint16Bit', '8bit', 'EnablePseudoGrayOutput', 'EnableNative10BitFramebuffer'};
			app.OKbitDepth.ValueChangedFcn = createCallbackFcn(app, @OKbitDepth_Callback, true);
			app.OKbitDepth.Tag = 'OKbitDepth';
			app.OKbitDepth.Value = 'FloatingPoint32BitIfPossible';
			app.OKverbosityLevel = uieditfield(app.DisplayTab, 'text');
			app.OKverbosityLevel.ValueChangedFcn = createCallbackFcn(app, @OKverbosityLevel_Callback, true);
			app.OKverbosityLevel.Tag = 'OKverbosityLevel';
			app.OKverbosityLevel.Tooltip = 'Level of verbosity logging used by PTB';
			app.OKverbosityLevel.Value = '4';
			app.OKbenchmark = uicheckbox(app.DisplayTab);
			app.OKbenchmark.ValueChangedFcn = createCallbackFcn(app, @OKbenchmark_Callback, true);
			app.OKbenchmark.Tag = 'OKbenchmark';
			app.OKbenchmark.Tooltip = 'Flip as fast as possible to test maximum framerate';
			app.OKbenchmark.Text = 'Benchmark';
			app.text203 = uilabel(app.DisplayTab);
			app.text203.Tag = 'text203';
			app.text203.Text = 'PTB Verbosity Level';
			app.OKbackgroundColour = uieditfield(app.DisplayTab, 'text');
			app.OKbackgroundColour.ValueChangedFcn = createCallbackFcn(app, @OKbackgroundColour_Callback, true);
			app.OKbackgroundColour.Tag = 'OKbackgroundColour';
			app.OKbackgroundColour.Value = '0.5 0.5 0.5 0';
			app.text8 = uilabel(app.DisplayTab);
			app.text8.Tag = 'text8';
			app.text8.Text = 'Background Colour';
			app.TaskTab = uitab(app.TabGroup);
			app.TaskTab.Title = 'Task';
			app.OKTrainingName = uieditfield(app.TaskTab, 'text');
			app.OKTrainingName.Tag = 'OKTrainingName';
			app.OKTrainingName.Enable = 'off';
			app.OKTrainingName.Value = 'Simulcra';
			app.text209 = uilabel(app.TaskTab);
			app.text209.Tag = 'text209';
			app.text209.Enable = 'off';
			app.text209.Text = 'Subject Name';
			app.OKLoadStateButton = uibutton(app.TaskTab, 'push');
			app.OKLoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @OKLoadStateButton_Callback, true);
			app.OKLoadStateButton.Tag = 'OKLoadStateButton';
			app.OKLoadStateButton.Text = 'Load State File';
			app.OKEditStateFileButon = uibutton(app.TaskTab, 'push');
			app.OKEditStateFileButon.ButtonPushedFcn = createCallbackFcn(app, @OKEditStateFileButon_Callback, true);
			app.OKEditStateFileButon.Tag = 'OKEditStateFileButon';
			app.OKEditStateFileButon.Text = 'Edit State File';
			app.OKTrainingResearcherName = uieditfield(app.TaskTab, 'text');
			app.OKTrainingResearcherName.Tag = 'OKTrainingResearcherName';
			app.OKTrainingResearcherName.Enable = 'off';
			app.OKTrainingResearcherName.Value = 'John Doe';
			app.text4554 = uilabel(app.TaskTab);
			app.text4554.Tag = 'text4554';
			app.text4554.Enable = 'off';
			app.text4554.Text = 'Researcher';
			app.OKTrainingText = uitextarea(app.TaskTab);
			app.OKTrainingText.Tag = 'OKTrainingText';
			app.OKTrainingText.Value = {'State Info here...'};
			app.OKRefreshStateInfo = uibutton(app.TaskTab, 'push');
			app.OKRefreshStateInfo.ButtonPushedFcn = createCallbackFcn(app, @OKRefreshStateInfo_Callback, true);
			app.OKRefreshStateInfo.Tag = 'OKRefreshStateInfo';
			app.OKRefreshStateInfo.Text = 'Refresh View...';
			app.OKTrainingFileName = uilabel(app.TaskTab);
			app.OKTrainingFileName.Tag = 'OKTrainingFileName';
			app.OKTrainingFileName.Text = 'File Name:';
			app.OthersTab = uitab(app.TabGroup);
			app.OthersTab.Title = 'Others';
			app.OKHistoryList = uilistbox(app.OthersTab);
			app.OKHistoryList.Items = {'Listbox'};
			app.OKHistoryList.Tag = 'OKHistoryList';
			app.OKHistoryList.Enable = 'off';
			app.OKHistoryList.Value = 'Listbox';
			app.text96 = uilabel(app.OthersTab);
			app.text96.Tag = 'text96';
			app.text96.Text = 'Protocols';
			app.text97 = uilabel(app.OthersTab);
			app.text97.Tag = 'text97';
			app.text97.Enable = 'off';
			app.text97.Text = 'History';
			app.OKProtocolLoad = uibutton(app.OthersTab, 'push');
			app.OKProtocolLoad.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolLoad_Callback, true);
			app.OKProtocolLoad.Tag = 'OKProtocolLoad';
			app.OKProtocolLoad.Text = 'Load Protocol';
			app.OKProtocolSave = uibutton(app.OthersTab, 'push');
			app.OKProtocolSave.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolSave_Callback, true);
			app.OKProtocolSave.Tag = 'OKProtocolSave';
			app.OKProtocolSave.Text = 'Save As...';
			app.OKProtocolDuplicate = uibutton(app.OthersTab, 'push');
			app.OKProtocolDuplicate.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolDuplicate_Callback, true);
			app.OKProtocolDuplicate.Tag = 'OKProtocolDuplicate';
			app.OKProtocolDuplicate.Enable = 'off';
			app.OKProtocolDuplicate.Text = 'Duplicate Protocol';
			app.OKProtocolDelete = uibutton(app.OthersTab, 'push');
			app.OKProtocolDelete.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolDelete_Callback, true);
			app.OKProtocolDelete.Tag = 'OKProtocolDelete';
			app.OKProtocolDelete.Text = 'Delete...';
			app.OKHistoryUp = uibutton(app.OthersTab, 'push');
			app.OKHistoryUp.Tag = 'OKHistoryUp';
			app.OKHistoryUp.Enable = 'off';
			app.OKHistoryUp.Text = 'Move Up';
			app.OKHistoryDown = uibutton(app.OthersTab, 'push');
			app.OKHistoryDown.Tag = 'OKHistoryDown';
			app.OKHistoryDown.Enable = 'off';
			app.OKHistoryDown.Text = 'Move Down';
			app.OKHistoryDelete = uibutton(app.OthersTab, 'push');
			app.OKHistoryDelete.Tag = 'OKHistoryDelete';
			app.OKHistoryDelete.Enable = 'off';
			app.OKHistoryDelete.Text = 'Delete';
			app.OKProtocolsList = uilistbox(app.OthersTab);
			app.OKProtocolsList.Items = {'X Y Grating', 'Patch Tuning', 'Plaid', 'Reverse Correlation'};
			app.OKProtocolsList.Tag = 'OKProtocolsList';
			app.OKProtocolsList.Value = 'X Y Grating';
			app.uipanel35 = uipanel(app.OthersTab);
			app.uipanel35.Tag = 'uipanel35';
			app.uipanel41 = uipanel(app.OthersTab);
			app.uipanel41.Tag = 'uipanel41';
			app.text181 = uilabel(app.OthersTab);
			app.text181.Tag = 'text181';
			app.text181.Text = 'Settings';
			app.OKSettingsmovieSize = uieditfield(app.OthersTab, 'text');
			app.OKSettingsmovieSize.ValueChangedFcn = createCallbackFcn(app, @OKSettingsmovieSize_Callback, true);
			app.OKSettingsmovieSize.Tag = 'OKSettingsmovieSize';
			app.OKSettingsmovieSize.Value = '400 400';
			app.OKSettingsmovieFrames = uieditfield(app.OthersTab, 'text');
			app.OKSettingsmovieFrames.ValueChangedFcn = createCallbackFcn(app, @OKSettingsmovieFrames_Callback, true);
			app.OKSettingsmovieFrames.Tag = 'OKSettingsmovieFrames';
			app.OKSettingsmovieFrames.Value = '100';
			app.OKSettingsmoviePrecision = uicheckbox(app.OthersTab);
			app.OKSettingsmoviePrecision.ValueChangedFcn = createCallbackFcn(app, @OKSettingsmoviePrecision_Callback, true);
			app.OKSettingsmoviePrecision.Tag = 'OKSettingsmoviePrecision';
			app.OKSettingsmoviePrecision.Enable = 'off';
			app.OKSettingsmoviePrecision.Text = 'Movie High Precision?';
			app.text182 = uilabel(app.OthersTab);
			app.text182.Tag = 'text182';
			app.text182.Text = 'Movie Size';
			app.text183 = uilabel(app.OthersTab);
			app.text183.Tag = 'text183';
			app.text183.Text = '# Frames';
			app.OKSettingsmovieType = uidropdown(app.OthersTab);
			app.OKSettingsmovieType.Items = {'GStreamer', 'Cortex'};
			app.OKSettingsmovieType.ValueChangedFcn = createCallbackFcn(app, @OKSettingsmovieType_Callback, true);
			app.OKSettingsmovieType.Tag = 'OKSettingsmovieType';
			app.OKSettingsmovieType.Enable = 'off';
			app.OKSettingsmovieType.Value = 'GStreamer';
			app.OKOmniplexIP = uieditfield(app.OthersTab, 'text');
			app.OKOmniplexIP.Tag = 'OKOmniplexIP';
			app.OKOmniplexIP.Value = '144.82.131.19';
			app.OKOmniplexPort = uieditfield(app.OthersTab, 'text');
			app.OKOmniplexPort.Tag = 'OKOmniplexPort';
			app.OKOmniplexPort.Value = '9889';
			app.text185 = uilabel(app.OthersTab);
			app.text185.Tag = 'text185';
			app.text185.Text = 'Port';
			app.OKSettingsmovieCodec = uieditfield(app.OthersTab, 'text');
			app.OKSettingsmovieCodec.ValueChangedFcn = createCallbackFcn(app, @OKSettingsmovieCodec_Callback, true);
			app.OKSettingsmovieCodec.Tag = 'OKSettingsmovieCodec';
			app.OKSettingsmovieCodec.Enable = 'off';
			app.text192 = uilabel(app.OthersTab);
			app.text192.Tag = 'text192';
			app.text192.Text = 'Codec';
			app.OKOmniplexEnable = uicheckbox(app.OthersTab);
			app.OKOmniplexEnable.ValueChangedFcn = createCallbackFcn(app, @OKOmniplexEnable_Callback, true);
			app.OKOmniplexEnable.Tag = 'OKOmniplexEnable';
			app.OKOmniplexEnable.Text = 'Omniplex?';
			app.text208 = uilabel(app.OthersTab);
			app.text208.Tag = 'text208';
			app.text208.Text = 'IP';
			app.OKeyeOccluder = uieditfield(app.OthersTab, 'text');
			app.OKeyeOccluder.Tag = 'OKeyeOccluder';
			app.OKeyeOccluder.Value = '/dev/tty.usbmodem5d11';
			% Show the figure after all components are created
			app.OKRoot.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = opticka_ui_app(varargin)

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.OKRoot)

				% Execute the startup function
				runStartupFcn(app, @(app)opticka_ui_OpeningFcn(app, varargin{:}))
			else

				% Focus the running singleton app
				figure(runningApp.OKRoot)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.OKRoot)
		end
	end
end